classdef DuyKha_Dec09_BaiTapNhom < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        NghimTab                      matlab.ui.container.Tab
        LoopFunctionEditField         matlab.ui.control.EditField
        fpEditFieldLabel              matlab.ui.control.Label
        IterationEditField            matlab.ui.control.NumericEditField
        EditField4Label               matlab.ui.control.Label
        ErrorEditField                matlab.ui.control.NumericEditField
        EditField3Label               matlab.ui.control.Label
        B_EditField                   matlab.ui.control.NumericEditField
        EditField2Label               matlab.ui.control.Label
        A_EditField                   matlab.ui.control.NumericEditField
        EditFieldLabel                matlab.ui.control.Label
        ResultEditField               matlab.ui.control.NumericEditField
        ResultLabel                   matlab.ui.control.Label
        ResetButton                   matlab.ui.control.Button
        PlotButton                    matlab.ui.control.Button
        MethodDropDown                matlab.ui.control.DropDown
        PhngphpDropDownLabel          matlab.ui.control.Label
        Equation_EditField            matlab.ui.control.EditField
        PhngtrnhEditFieldLabel        matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        NisuyTab                      matlab.ui.container.Tab
        ResetButton_2                 matlab.ui.control.Button
        KtqunisuyEditField            matlab.ui.control.NumericEditField
        KtqunisuyEditFieldLabel       matlab.ui.control.Label
        KtquathcnisuyEditField        matlab.ui.control.NumericEditField
        KtquathcnisuyEditFieldLabel   matlab.ui.control.Label
        NhpgitrcnnisuyEditField       matlab.ui.control.EditField
        NhpgitrcnnisuyEditFieldLabel  matlab.ui.control.Label
        PhngphpDropDown_2             matlab.ui.control.DropDown
        PhngphpDropDown_2Label        matlab.ui.control.Label
        NhpdliuyEditField             matlab.ui.control.EditField
        NhpdliuyEditFieldLabel        matlab.ui.control.Label
        NhpdliuxEditField             matlab.ui.control.EditField
        NhpdliuxEditFieldLabel        matlab.ui.control.Label
        UIAxes_2                      matlab.ui.control.UIAxes
        HiquyTab                      matlab.ui.container.Tab
        KtqudonEditField              matlab.ui.control.NumericEditField
        KtqudonEditFieldLabel         matlab.ui.control.Label
        KtquphngtrnhhiquyEditField    matlab.ui.control.NumericEditField
        KtquphngtrnhhiquyLabel        matlab.ui.control.Label
        NhpgitrcndonEditField         matlab.ui.control.EditField
        NhpgitrcndonEditFieldLabel    matlab.ui.control.Label
        PhngphpDropDown_3             matlab.ui.control.DropDown
        PhngphpDropDown_3Label        matlab.ui.control.Label
        NhpdliuyEditField_2           matlab.ui.control.EditField
        NhpdliuyEditField_2Label      matlab.ui.control.Label
        NhpdliuxEditField_2           matlab.ui.control.EditField
        NhpdliuxEditField_2Label      matlab.ui.control.Label
        ResetButton_3                 matlab.ui.control.Button
        UIAxes_3                      matlab.ui.control.UIAxes
        ohmTab                        matlab.ui.container.Tab
        KtquEditField                 matlab.ui.control.NumericEditField
        KtquEditFieldLabel            matlab.ui.control.Label
        PhngphpohmDropDown            matlab.ui.control.DropDown
        PhngphpohmDropDownLabel       matlab.ui.control.Label
        GitrsaisDropDown              matlab.ui.control.DropDown
        GitrsaisDropDownLabel         matlab.ui.control.Label
        NhpbchEditField               matlab.ui.control.EditField
        NhpbchEditFieldLabel          matlab.ui.control.Label
        NhphmsEditField               matlab.ui.control.EditField
        NhphmsEditFieldLabel          matlab.ui.control.Label
        HocLabel                      matlab.ui.control.Label
        NhpdliuyEditField_3           matlab.ui.control.EditField
        NhpdliuyEditField_3Label      matlab.ui.control.Label
        NhpdliuxEditField_3           matlab.ui.control.EditField
        NhpdliuxEditField_3Label      matlab.ui.control.Label
        TchphnTab                     matlab.ui.container.Tab
        NhpNEditField                 matlab.ui.control.EditField
        NhpNEditFieldLabel            matlab.ui.control.Label
        NhpcntnhtchphnEditField       matlab.ui.control.EditField
        NhpcntnhtchphnEditFieldLabel  matlab.ui.control.Label
        KtquEditField_2               matlab.ui.control.NumericEditField
        KtquEditField_2Label          matlab.ui.control.Label
        PhngphpTchphnDropDown         matlab.ui.control.DropDown
        PhngphpTchphnDropDownLabel    matlab.ui.control.Label
        NhphmsEditField_2             matlab.ui.control.EditField
        NhphmsEditField_2Label        matlab.ui.control.Label
        NhpdliuyEditField_4           matlab.ui.control.EditField
        NhpdliuyEditField_4Label      matlab.ui.control.Label
        NhpdliuxEditField_4           matlab.ui.control.EditField
        NhpdliuxEditField_4Label      matlab.ui.control.Label
        HocLabel_2                    matlab.ui.control.Label
        GiithiunhmTab                 matlab.ui.container.Tab
        NhimvTextArea                 matlab.ui.control.TextArea
        NhimvTextAreaLabel            matlab.ui.control.Label
        GiithiuthnhvinTextArea        matlab.ui.control.TextArea
        GiithiuthnhvinTextAreaLabel   matlab.ui.control.Label
        GiithiunhmTextArea            matlab.ui.control.TextArea
        GiithiunhmTextAreaLabel       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            fun = app.Equation_EditField.Value;
            fp = app.LoopFunctionEditField.Value;
            a = app.A_EditField.Value;
            b = app.B_EditField.Value;
            method_1 = app.MethodDropDown.Value;
            method_1 = string(method_1);
            es = app.ErrorEditField.Value; %Sai số cho trước
            max = app.IterationEditField.Value;
            x = -10:0.0001:10;
            n = 0;
            switch method_1
                case {'Chia đôi'}
                    fx = str2func(['@(x)',fun]);
                    y = fx(x);
                    while n < max
                        n = n+1;
                        c = (a+b)/2;
                        fafc = fx(a)*fx(c);
                        if fafc < 0
                            b = c;
                        else
                            a = c;
                        end
                        ea = abs(b - a);
                        if (fafc == 0)
                            ea=0;
                        end
                        if ea < es
                            break
                        end
                    end
                plot(app.UIAxes,x,y);
                app.ResultEditField.Value = c;
                case {'Lặp'} 
                    fx = str2func(['@(x)',fun]);
                    y = fx(x);
                    fp = str2func(['@(x)',fp]);
                    c = (a+b)/2;
                    if fx(a) * fx(c) < 0
                        x0 = b;
                    else
                        x0 = a;
                    end
                    while n < max
                        n = n+1;
                        x1 = fp(x0);
                        ea = abs(x1 - x0);
                        if ea < es
                            break;
                        end
                        x0 = x1;
                    end
                    plot(app.UIAxes,x,y);
                    app.ResultEditField.Value = x1;
                case {'Newton'}
                    fx = str2sym(fun);
                    fp = diff(fx);
                    fpp = diff(fp);
                    fp = matlabFunction(fp);
                    fpp = matlabFunction(fpp);
                    fx = matlabFunction(fx);
                    n = 0;
                    if fx(a) * fpp(a) > 0
                        x0 = b;
                    else
                        x0 = a;
                    end
                    while n < max
                        n = n+1;
                        x1 = x0 - (fx(x0)/fp(x0));
                        ea = abs(x1 - x0);
                        if ea < es
                            break;
                        end
                        x0 = x1;
                    end
                    y = fx(x);
                    plot(app.UIAxes,x,y);
                    app.ResultEditField.Value = x1;
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.A_EditField.Value = 0;
            app.B_EditField.Value = 0;
            app.Equation_EditField.Value = '';
            app.LoopFunctionEditField.Value = '';
            app.ErrorEditField.Value = 0;
            app.IterationEditField.Value = 0;
            app.ResultEditField.Value = 0;
            plot(app.UIAxes,0,0);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghimTab = uitab(app.TabGroup);
            app.NghimTab.Title = 'Nghiệm';
            app.UIAxes = uiaxes(app.NghimTab);
            title(app.UIAxes, 'Đồ thị Hàm số')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PhngtrnhEditFieldLabel = uilabel(app.NghimTab);
            app.PhngtrnhEditFieldLabel.Text = 'Phương trình:';
            app.Equation_EditField = uieditfield(app.NghimTab, 'text');
            app.PhngphpDropDownLabel = uilabel(app.NghimTab);
            app.PhngphpDropDownLabel.Text = 'Phương pháp:';
            app.MethodDropDown = uidropdown(app.NghimTab);
            app.MethodDropDown.Items = {'Chia đôi', 'Lặp', 'Newton'};
            app.MethodDropDown.Value = 'Chia đôi';
            app.PlotButton = uibutton(app.NghimTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Tính toán';
            app.ResetButton = uibutton(app.NghimTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ResultLabel = uilabel(app.NghimTab);
            app.ResultLabel.Text = 'Result:';
            app.ResultEditField = uieditfield(app.NghimTab, 'numeric');
            app.EditFieldLabel = uilabel(app.NghimTab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.A_EditField = uieditfield(app.NghimTab, 'numeric');
            app.EditField2Label = uilabel(app.NghimTab);
            app.EditField2Label.Text = 'Edit Field2';
            app.B_EditField = uieditfield(app.NghimTab, 'numeric');
            app.EditField3Label = uilabel(app.NghimTab);
            app.EditField3Label.Text = 'Edit Field3';
            app.ErrorEditField = uieditfield(app.NghimTab, 'numeric');
            app.EditField4Label = uilabel(app.NghimTab);
            app.EditField4Label.Text = 'Edit Field4';
            app.IterationEditField = uieditfield(app.NghimTab, 'numeric');
            app.fpEditFieldLabel = uilabel(app.NghimTab);
            app.fpEditFieldLabel.Text = 'fp';
            app.LoopFunctionEditField = uieditfield(app.NghimTab, 'text');
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.UIAxes_2 = uiaxes(app.NisuyTab);
            title(app.UIAxes_2, 'Đồ thị số nội suy và dữ liệu thực ')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.NhpdliuxEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x:';
            app.NhpdliuxEditField = uieditfield(app.NisuyTab, 'text');
            app.NhpdliuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y:';
            app.NhpdliuyEditField = uieditfield(app.NisuyTab, 'text');
            app.PhngphpDropDown_2Label = uilabel(app.NisuyTab);
            app.PhngphpDropDown_2Label.Text = 'Phương pháp:';
            app.PhngphpDropDown_2 = uidropdown(app.NisuyTab);
            app.PhngphpDropDown_2.Items = {'Newton', 'Lagrange'};
            app.PhngphpDropDown_2.Value = 'Newton';
            app.NhpgitrcnnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpgitrcnnisuyEditFieldLabel.Text = 'Nhập giá trị cần nội suy';
            app.NhpgitrcnnisuyEditField = uieditfield(app.NisuyTab, 'text');
            app.KtquathcnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtquathcnisuyEditFieldLabel.Text = 'Kết quả đa thức nội suy:';
            app.KtquathcnisuyEditField = uieditfield(app.NisuyTab, 'numeric');
            app.KtqunisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtqunisuyEditFieldLabel.Text = 'Kết quả nội suy:';
            app.KtqunisuyEditField = uieditfield(app.NisuyTab, 'numeric');
            app.ResetButton_2 = uibutton(app.NisuyTab, 'push');
            app.ResetButton_2.Text = 'Reset';
            app.HiquyTab = uitab(app.TabGroup);
            app.HiquyTab.Title = 'Hồi quy';
            app.UIAxes_3 = uiaxes(app.HiquyTab);
            title(app.UIAxes_3, 'Đồ thị số hồi quy và dữ liệu thực ')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.ResetButton_3 = uibutton(app.HiquyTab, 'push');
            app.ResetButton_3.Text = 'Reset';
            app.NhpdliuxEditField_2Label = uilabel(app.HiquyTab);
            app.NhpdliuxEditField_2Label.Text = 'Nhập dữ liệu x:';
            app.NhpdliuxEditField_2 = uieditfield(app.HiquyTab, 'text');
            app.NhpdliuyEditField_2Label = uilabel(app.HiquyTab);
            app.NhpdliuyEditField_2Label.Text = 'Nhập dữ liệu y:';
            app.NhpdliuyEditField_2 = uieditfield(app.HiquyTab, 'text');
            app.PhngphpDropDown_3Label = uilabel(app.HiquyTab);
            app.PhngphpDropDown_3Label.Text = 'Phương pháp:';
            app.PhngphpDropDown_3 = uidropdown(app.HiquyTab);
            app.PhngphpDropDown_3.Items = {'Tuyến tính', 'Hàm mũ', 'Logarit'};
            app.PhngphpDropDown_3.Value = 'Logarit';
            app.NhpgitrcndonEditFieldLabel = uilabel(app.HiquyTab);
            app.NhpgitrcndonEditFieldLabel.Text = 'Nhập giá trị cần dự đoán:';
            app.NhpgitrcndonEditField = uieditfield(app.HiquyTab, 'text');
            app.KtquphngtrnhhiquyLabel = uilabel(app.HiquyTab);
            app.KtquphngtrnhhiquyLabel.Text = 'Kết quả phương trình hồi quy:';
            app.KtquphngtrnhhiquyEditField = uieditfield(app.HiquyTab, 'numeric');
            app.KtqudonEditFieldLabel = uilabel(app.HiquyTab);
            app.KtqudonEditFieldLabel.Text = 'Kết quả dự đoán:';
            app.KtqudonEditField = uieditfield(app.HiquyTab, 'numeric');
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.NhpdliuxEditField_3Label = uilabel(app.ohmTab);
            app.NhpdliuxEditField_3Label.Text = 'Nhập dữ liệu x:';
            app.NhpdliuxEditField_3 = uieditfield(app.ohmTab, 'text');
            app.NhpdliuyEditField_3Label = uilabel(app.ohmTab);
            app.NhpdliuyEditField_3Label.Text = 'Nhập dữ liệu y:';
            app.NhpdliuyEditField_3 = uieditfield(app.ohmTab, 'text');
            app.HocLabel = uilabel(app.ohmTab);
            app.HocLabel.Text = 'Hoặc';
            app.NhphmsEditFieldLabel = uilabel(app.ohmTab);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số:';
            app.NhphmsEditField = uieditfield(app.ohmTab, 'text');
            app.NhpbchEditFieldLabel = uilabel(app.ohmTab);
            app.NhpbchEditFieldLabel.Text = 'Nhập bước h:';
            app.NhpbchEditField = uieditfield(app.ohmTab, 'text');
            app.GitrsaisDropDownLabel = uilabel(app.ohmTab);
            app.GitrsaisDropDownLabel.Text = 'Giá trị sai số:';
            app.GitrsaisDropDown = uidropdown(app.ohmTab);
            app.GitrsaisDropDown.Items = {'O(h)', 'O(h^2)'};
            app.GitrsaisDropDown.Value = 'O(h)';
            app.PhngphpohmDropDownLabel = uilabel(app.ohmTab);
            app.PhngphpohmDropDownLabel.Text = 'Phương pháp đạo hàm:';
            app.PhngphpohmDropDown = uidropdown(app.ohmTab);
            app.PhngphpohmDropDown.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Trung tâm'};
            app.PhngphpohmDropDown.Value = 'Xấp xỉ tiến';
            app.KtquEditFieldLabel = uilabel(app.ohmTab);
            app.KtquEditFieldLabel.Text = 'Kết quả:';
            app.KtquEditField = uieditfield(app.ohmTab, 'numeric');
            app.TchphnTab = uitab(app.TabGroup);
            app.TchphnTab.Title = 'Tích phân';
            app.HocLabel_2 = uilabel(app.TchphnTab);
            app.HocLabel_2.Text = 'Hoặc';
            app.NhpdliuxEditField_4Label = uilabel(app.TchphnTab);
            app.NhpdliuxEditField_4Label.Text = 'Nhập dữ liệu x:';
            app.NhpdliuxEditField_4 = uieditfield(app.TchphnTab, 'text');
            app.NhpdliuyEditField_4Label = uilabel(app.TchphnTab);
            app.NhpdliuyEditField_4Label.Text = 'Nhập dữ liệu y:';
            app.NhpdliuyEditField_4 = uieditfield(app.TchphnTab, 'text');
            app.NhphmsEditField_2Label = uilabel(app.TchphnTab);
            app.NhphmsEditField_2Label.Text = 'Nhập hàm số:';
            app.NhphmsEditField_2 = uieditfield(app.TchphnTab, 'text');
            app.PhngphpTchphnDropDownLabel = uilabel(app.TchphnTab);
            app.PhngphpTchphnDropDownLabel.Text = 'Phương pháp Tích phân:';
            app.PhngphpTchphnDropDown = uidropdown(app.TchphnTab);
            app.PhngphpTchphnDropDown.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.PhngphpTchphnDropDown.Value = 'Hình thang';
            app.KtquEditField_2Label = uilabel(app.TchphnTab);
            app.KtquEditField_2Label.Text = 'Kết quả:';
            app.KtquEditField_2 = uieditfield(app.TchphnTab, 'numeric');
            app.NhpcntnhtchphnEditFieldLabel = uilabel(app.TchphnTab);
            app.NhpcntnhtchphnEditFieldLabel.Text = 'Nhập cận để tính tích phân:';
            app.NhpcntnhtchphnEditField = uieditfield(app.TchphnTab, 'text');
            app.NhpNEditFieldLabel = uilabel(app.TchphnTab);
            app.NhpNEditFieldLabel.Text = 'Nhập N:';
            app.NhpNEditField = uieditfield(app.TchphnTab, 'text');
            app.GiithiunhmTab = uitab(app.TabGroup);
            app.GiithiunhmTab.Title = 'Giới thiệu nhóm';
            app.GiithiunhmTextAreaLabel = uilabel(app.GiithiunhmTab);
            app.GiithiunhmTextAreaLabel.Text = 'Giới thiệu nhóm:';
            app.GiithiunhmTextArea = uitextarea(app.GiithiunhmTab);
            app.GiithiuthnhvinTextAreaLabel = uilabel(app.GiithiunhmTab);
            app.GiithiuthnhvinTextAreaLabel.Text = 'Giới thiệu thành viên:';
            app.GiithiuthnhvinTextArea = uitextarea(app.GiithiunhmTab);
            app.NhimvTextAreaLabel = uilabel(app.GiithiunhmTab);
            app.NhimvTextAreaLabel.Text = 'Nhiệm vụ:';
            app.NhimvTextArea = uitextarea(app.GiithiunhmTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DuyKha_Dec09_BaiTapNhom

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end