classdef HuyHoang_Dec9_HoiQuy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        NghimTab                      matlab.ui.container.Tab
        PhngtrnhEditFieldLabel        matlab.ui.control.Label
        PhngtrnhEditField             matlab.ui.control.EditField
        KhongphnlyEditFieldLabel      matlab.ui.control.Label
        KhongphnlyEditField           matlab.ui.control.NumericEditField
        nEditFieldLabel               matlab.ui.control.Label
        nEditField                    matlab.ui.control.NumericEditField
        SaisEditFieldLabel            matlab.ui.control.Label
        SaisEditField                 matlab.ui.control.EditField
        PhngphpDropDownLabel          matlab.ui.control.Label
        PhngphpDropDown               matlab.ui.control.DropDown
        SlnlpLabel                    matlab.ui.control.Label
        SlnlpEditField                matlab.ui.control.EditField
        KtqunghimEditFieldLabel       matlab.ui.control.Label
        KtqunghimEditField            matlab.ui.control.NumericEditField
        TnhtonButton                  matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        NisuyTab                      matlab.ui.container.Tab
        NhpdliuxEditFieldLabel        matlab.ui.control.Label
        NhpdliuxEditField             matlab.ui.control.EditField
        NhpdliuyEditFieldLabel        matlab.ui.control.Label
        NhpdliuyEditField             matlab.ui.control.EditField
        PhngphpDropDown_2Label        matlab.ui.control.Label
        PhngphpDropDown_2             matlab.ui.control.DropDown
        NhpgitrcnnisuyEditFieldLabel  matlab.ui.control.Label
        NhpgitrcnnisuyEditField       matlab.ui.control.EditField
        KtquathcnisuyEditFieldLabel   matlab.ui.control.Label
        KtquathcnisuyEditField        matlab.ui.control.NumericEditField
        KtqunisuyEditFieldLabel       matlab.ui.control.Label
        KtqunisuyEditField            matlab.ui.control.NumericEditField
        ResetButton_2                 matlab.ui.control.Button
        UIAxes_2                      matlab.ui.control.UIAxes
        HiquyTab                      matlab.ui.container.Tab
        ResetButton_3                 matlab.ui.control.Button
        NhpdliuxEditField_2Label      matlab.ui.control.Label
        NhpdliuxEditField_2           matlab.ui.control.EditField
        NhpdliuyEditField_2Label      matlab.ui.control.Label
        NhpdliuyEditField_2           matlab.ui.control.EditField
        PhngphpDropDown_3Label        matlab.ui.control.Label
        PhngphpDropDown_3             matlab.ui.control.DropDown
        KtqudonEditFieldLabel         matlab.ui.control.Label
        KtqudonEditField              matlab.ui.control.NumericEditField
        Button                        matlab.ui.control.Button
        NhpgitrcndonEditFieldLabel    matlab.ui.control.Label
        NhpgitrcndonEditField         matlab.ui.control.NumericEditField
        KtquphngtrnhhiquyEditFieldLabel  matlab.ui.control.Label
        KtquphngtrnhhiquyEditField    matlab.ui.control.EditField
        UIAxes_3                      matlab.ui.control.UIAxes
        ohmTab                        matlab.ui.container.Tab
        NhpdliuxEditField_3Label      matlab.ui.control.Label
        NhpdliuxEditField_3           matlab.ui.control.EditField
        NhpdliuyEditField_3Label      matlab.ui.control.Label
        NhpdliuyEditField_3           matlab.ui.control.EditField
        HocLabel                      matlab.ui.control.Label
        NhphmsEditFieldLabel          matlab.ui.control.Label
        NhphmsEditField               matlab.ui.control.EditField
        NhpbchEditFieldLabel          matlab.ui.control.Label
        NhpbchEditField               matlab.ui.control.EditField
        GitrsaisDropDownLabel         matlab.ui.control.Label
        GitrsaisDropDown              matlab.ui.control.DropDown
        PhngphpohmDropDownLabel       matlab.ui.control.Label
        PhngphpohmDropDown            matlab.ui.control.DropDown
        KtquEditFieldLabel            matlab.ui.control.Label
        KtquEditField                 matlab.ui.control.NumericEditField
        TchphnTab                     matlab.ui.container.Tab
        HocLabel_2                    matlab.ui.control.Label
        NhpdliuxEditField_4Label      matlab.ui.control.Label
        NhpdliuxEditField_4           matlab.ui.control.EditField
        NhpdliuyEditField_4Label      matlab.ui.control.Label
        NhpdliuyEditField_4           matlab.ui.control.EditField
        NhphmsEditField_2Label        matlab.ui.control.Label
        NhphmsEditField_2             matlab.ui.control.EditField
        PhngphpTchphnDropDownLabel    matlab.ui.control.Label
        PhngphpTchphnDropDown         matlab.ui.control.DropDown
        KtquEditField_2Label          matlab.ui.control.Label
        KtquEditField_2               matlab.ui.control.NumericEditField
        NhpcntnhtchphnEditFieldLabel  matlab.ui.control.Label
        NhpcntnhtchphnEditField       matlab.ui.control.EditField
        NhpNEditFieldLabel            matlab.ui.control.Label
        NhpNEditField                 matlab.ui.control.EditField
        GiithiunhmTab                 matlab.ui.container.Tab
        GiithiunhmTextAreaLabel       matlab.ui.control.Label
        GiithiunhmTextArea            matlab.ui.control.TextArea
        GiithiuthnhvinTextAreaLabel   matlab.ui.control.Label
        GiithiuthnhvinTextArea        matlab.ui.control.TextArea
        NhimvTextAreaLabel            matlab.ui.control.Label
        NhimvTextArea                 matlab.ui.control.TextArea
    end

    
    methods (Access = private)
        
            function [A1, A0] = hoiQuyTinhToan(app, X, Y, n)
            sumX=0; sumY=0; sumXY=0; sumX2=0;
            for i = 1:n
                sumX = sumX + X(i);
                sumY = sumY + Y(i);
                sumXY = sumXY + X(i)*Y(i);
                sumX2 = sumX2 + X(i)*X(i);
            end
            Xm = sumX / n;
            Ym = sumY / n;
            A1 = (n*sumXY - sumX*sumY) / (n*sumX2 - sumX*sumX);
            A0 = Ym - A1*Xm;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ResetButton_3
        function ResetButton_3Pushed(app, event)
            app.NhpdliuxEditField_2.Value = '';
            app.NhpdliuyEditField_2.Value = '';
            app.PhngphpDropDown_3.Value = 'P(x) = ax + b';
            app.NhpgitrcndonEditField.Value = 0;
            app.KtquphngtrnhhiquyEditField.Value = '';
            app.KtqudonEditField.Value = 0;
            cla(app.UIAxes_3);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Lấy dữ liệu từ client
            x_text = app.NhpdliuxEditField_2.Value;
            y_text = app.NhpdliuyEditField_2.Value;
            P_text = app.PhngphpDropDown_3.Value;
            x_find = app.NhpgitrcndonEditField.Value;
            
            % Chuyển đổi và khai báo
            x = str2num(x_text);
            y = str2num(y_text);
            n = length(x);
            if(length(x) ~= length(y))
                ResetButton_3Pushed(app, event);
                app.KtquphngtrnhhiquyEditField.Value = 'Lỗi';
                return;
            end
                      
            % Tính toán
            if(isequal(P_text, 'P(x) = ax + b'))
                % Data for test
                % x = [6 7 11 15 17 21 23 29 29 37 39]
                % y =   
                X = x; Y = y;
                [A1, A0] = hoiQuyTinhToan(app, X, Y, n);
                a = A1; b = A0;
                
                app.KtquphngtrnhhiquyEditField.Value = [num2str(a) 'x + ' num2str(b)];
                app.KtqudonEditField.Value = a*x_find + b;
                cla(app.UIAxes_3);
                hold(app.UIAxes_3, 'on');
                plot(app.UIAxes_3, x, y, 'o');
                plot(app.UIAxes_3, x, a*x + b);
                legend(app.UIAxes_3, 'f(xi)', 'P(x)');
                hold(app.UIAxes_3, 'off');  
            elseif(isequal(P_text, 'P(x) = ax^b'))
                % Data for test
                % [1 2 3 4 5]
                % [0.5 1.7 3.4 5.7 8.4]
                X = log10(x); Y = log10(y);
                [A1, A0] = hoiQuyTinhToan(app, X, Y, n);
                a = 10.^A0; b = A1;
                
                app.KtquphngtrnhhiquyEditField.Value = [num2str(a) 'x^' num2str(b)];
                app.KtqudonEditField.Value = a * x_find.^b;
                cla(app.UIAxes_3);
                hold(app.UIAxes_3, 'on');
                plot(app.UIAxes_3, x, y, 'o');
                plot(app.UIAxes_3, x, a * x.^b);
                legend(app.UIAxes_3, 'f(xi)', 'P(x)');
                hold(app.UIAxes_3, 'off');
            elseif(isequal(P_text, 'P(x) = aln(x) + b'))
                % Data for test
                % [0.25 2.0 4.0 7.0 10.1]
                % [0 4.7 6.4 7.7 8.6]
                X = log(x); Y = y;
                [A1, A0] = hoiQuyTinhToan(app, X, Y, n);
                a = A1; b = A0;
                app.KtquphngtrnhhiquyEditField.Value = [num2str(a) 'ln(x) + ' num2str(b)];
                app.KtqudonEditField.Value = a*log(x_find) + b;
                cla(app.UIAxes_3);
                hold(app.UIAxes_3, 'on');
                plot(app.UIAxes_3, x, y, 'o');
                plot(app.UIAxes_3, x, a*log(x) + b);
                legend(app.UIAxes_3, 'f(xi)', 'P(x)');
                hold(app.UIAxes_3, 'off');
            elseif(isequal(P_text, 'P(x) = ae^(bx)'))
                % Data for test
                % [0.65 0.75 0.85 0.95 1.15]
                % [0.96 1.06 1.17 1.29 1.58]
                X = x; Y = log(y);
                [A1, A0] = hoiQuyTinhToan(app, X, Y, n);
                a = exp(A0); b = A1;
                app.KtquphngtrnhhiquyEditField.Value = [num2str(a) 'e^(' num2str(b) 'x)'];
                app.KtqudonEditField.Value = a*exp(b*x_find);
                cla(app.UIAxes_3);
                hold(app.UIAxes_3, 'on');
                plot(app.UIAxes_3, x, y, 'o');
                plot(app.UIAxes_3, x, a*exp(b*x));
                legend(app.UIAxes_3, 'f(xi)', 'P(x)');
                hold(app.UIAxes_3, 'off');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghimTab = uitab(app.TabGroup);
            app.NghimTab.Title = 'Nghiệm';
            app.PhngtrnhEditFieldLabel = uilabel(app.NghimTab);
            app.PhngtrnhEditFieldLabel.Text = 'Phương trình:';
            app.PhngtrnhEditField = uieditfield(app.NghimTab, 'text');
            app.KhongphnlyEditFieldLabel = uilabel(app.NghimTab);
            app.KhongphnlyEditFieldLabel.Text = 'Khoảng phân ly:';
            app.KhongphnlyEditField = uieditfield(app.NghimTab, 'numeric');
            app.nEditFieldLabel = uilabel(app.NghimTab);
            app.nEditFieldLabel.Text = 'đến';
            app.nEditField = uieditfield(app.NghimTab, 'numeric');
            app.SaisEditFieldLabel = uilabel(app.NghimTab);
            app.SaisEditFieldLabel.Text = 'Sai số:';
            app.SaisEditField = uieditfield(app.NghimTab, 'text');
            app.SaisEditField.Value = '0';
            app.PhngphpDropDownLabel = uilabel(app.NghimTab);
            app.PhngphpDropDownLabel.Text = 'Phương pháp:';
            app.PhngphpDropDown = uidropdown(app.NghimTab);
            app.PhngphpDropDown.Items = {'Chia đôi', 'Lặp', 'Newton'};
            app.PhngphpDropDown.Value = 'Chia đôi';
            app.SlnlpLabel = uilabel(app.NghimTab);
            app.SlnlpLabel.Text = 'Số lần lặp:';
            app.SlnlpEditField = uieditfield(app.NghimTab, 'text');
            app.SlnlpEditField.Value = '0';
            app.KtqunghimEditFieldLabel = uilabel(app.NghimTab);
            app.KtqunghimEditFieldLabel.Text = 'Kết quả nghiệm:';
            app.KtqunghimEditField = uieditfield(app.NghimTab, 'numeric');
            app.TnhtonButton = uibutton(app.NghimTab, 'push');
            app.TnhtonButton.Text = 'Tính toán';
            app.ResetButton = uibutton(app.NghimTab, 'push');
            app.ResetButton.Text = 'Reset';
            app.UIAxes = uiaxes(app.NghimTab);
            title(app.UIAxes, 'Đồ thị Hàm số')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.NhpdliuxEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x:';
            app.NhpdliuxEditField = uieditfield(app.NisuyTab, 'text');
            app.NhpdliuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y:';
            app.NhpdliuyEditField = uieditfield(app.NisuyTab, 'text');
            app.PhngphpDropDown_2Label = uilabel(app.NisuyTab);
            app.PhngphpDropDown_2Label.Text = 'Phương pháp:';
            app.PhngphpDropDown_2 = uidropdown(app.NisuyTab);
            app.PhngphpDropDown_2.Items = {'Newton', 'Lagrange'};
            app.PhngphpDropDown_2.Value = 'Newton';
            app.NhpgitrcnnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpgitrcnnisuyEditFieldLabel.Text = 'Nhập giá trị cần nội suy';
            app.NhpgitrcnnisuyEditField = uieditfield(app.NisuyTab, 'text');
            app.KtquathcnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtquathcnisuyEditFieldLabel.Text = 'Kết quả đa thức nội suy:';
            app.KtquathcnisuyEditField = uieditfield(app.NisuyTab, 'numeric');
            app.KtqunisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtqunisuyEditFieldLabel.Text = 'Kết quả nội suy:';
            app.KtqunisuyEditField = uieditfield(app.NisuyTab, 'numeric');
            app.ResetButton_2 = uibutton(app.NisuyTab, 'push');
            app.ResetButton_2.Text = 'Reset';
            app.UIAxes_2 = uiaxes(app.NisuyTab);
            title(app.UIAxes_2, 'Đồ thị số nội suy và dữ liệu thực ')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.HiquyTab = uitab(app.TabGroup);
            app.HiquyTab.Title = 'Hồi quy';
            app.ResetButton_3 = uibutton(app.HiquyTab, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_3Pushed, true);
            app.ResetButton_3.Text = 'Reset';
            app.NhpdliuxEditField_2Label = uilabel(app.HiquyTab);
            app.NhpdliuxEditField_2Label.Text = 'Nhập dữ liệu x:';
            app.NhpdliuxEditField_2 = uieditfield(app.HiquyTab, 'text');
            app.NhpdliuyEditField_2Label = uilabel(app.HiquyTab);
            app.NhpdliuyEditField_2Label.Text = 'Nhập dữ liệu y:';
            app.NhpdliuyEditField_2 = uieditfield(app.HiquyTab, 'text');
            app.PhngphpDropDown_3Label = uilabel(app.HiquyTab);
            app.PhngphpDropDown_3Label.Text = 'Phương pháp:';
            app.PhngphpDropDown_3 = uidropdown(app.HiquyTab);
            app.PhngphpDropDown_3.Items = {'P(x) = ax + b', 'P(x) = ax^b', 'P(x) = aln(x) + b', 'P(x) = ae^(bx)'};
            app.PhngphpDropDown_3.Value = 'P(x) = ax + b';
            app.KtqudonEditFieldLabel = uilabel(app.HiquyTab);
            app.KtqudonEditFieldLabel.Text = 'Kết quả dự đoán:';
            app.KtqudonEditField = uieditfield(app.HiquyTab, 'numeric');
            app.Button = uibutton(app.HiquyTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.NhpgitrcndonEditFieldLabel = uilabel(app.HiquyTab);
            app.NhpgitrcndonEditFieldLabel.Text = 'Nhập giá trị cần dự đoán';
            app.NhpgitrcndonEditField = uieditfield(app.HiquyTab, 'numeric');
            app.KtquphngtrnhhiquyEditFieldLabel = uilabel(app.HiquyTab);
            app.KtquphngtrnhhiquyEditFieldLabel.Text = 'Kết quả phương trình hồi quy';
            app.KtquphngtrnhhiquyEditField = uieditfield(app.HiquyTab, 'text');
            app.UIAxes_3 = uiaxes(app.HiquyTab);
            title(app.UIAxes_3, 'Đồ thị số hồi quy và dữ liệu thực ')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.NhpdliuxEditField_3Label = uilabel(app.ohmTab);
            app.NhpdliuxEditField_3Label.Text = 'Nhập dữ liệu x:';
            app.NhpdliuxEditField_3 = uieditfield(app.ohmTab, 'text');
            app.NhpdliuyEditField_3Label = uilabel(app.ohmTab);
            app.NhpdliuyEditField_3Label.Text = 'Nhập dữ liệu y:';
            app.NhpdliuyEditField_3 = uieditfield(app.ohmTab, 'text');
            app.HocLabel = uilabel(app.ohmTab);
            app.HocLabel.Text = 'Hoặc';
            app.NhphmsEditFieldLabel = uilabel(app.ohmTab);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số:';
            app.NhphmsEditField = uieditfield(app.ohmTab, 'text');
            app.NhpbchEditFieldLabel = uilabel(app.ohmTab);
            app.NhpbchEditFieldLabel.Text = 'Nhập bước h:';
            app.NhpbchEditField = uieditfield(app.ohmTab, 'text');
            app.GitrsaisDropDownLabel = uilabel(app.ohmTab);
            app.GitrsaisDropDownLabel.Text = 'Giá trị sai số:';
            app.GitrsaisDropDown = uidropdown(app.ohmTab);
            app.GitrsaisDropDown.Items = {'O(h)', 'O(h^2)'};
            app.GitrsaisDropDown.Value = 'O(h)';
            app.PhngphpohmDropDownLabel = uilabel(app.ohmTab);
            app.PhngphpohmDropDownLabel.Text = 'Phương pháp đạo hàm:';
            app.PhngphpohmDropDown = uidropdown(app.ohmTab);
            app.PhngphpohmDropDown.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Trung tâm'};
            app.PhngphpohmDropDown.Value = 'Xấp xỉ tiến';
            app.KtquEditFieldLabel = uilabel(app.ohmTab);
            app.KtquEditFieldLabel.Text = 'Kết quả:';
            app.KtquEditField = uieditfield(app.ohmTab, 'numeric');
            app.TchphnTab = uitab(app.TabGroup);
            app.TchphnTab.Title = 'Tích phân';
            app.HocLabel_2 = uilabel(app.TchphnTab);
            app.HocLabel_2.Text = 'Hoặc';
            app.NhpdliuxEditField_4Label = uilabel(app.TchphnTab);
            app.NhpdliuxEditField_4Label.Text = 'Nhập dữ liệu x:';
            app.NhpdliuxEditField_4 = uieditfield(app.TchphnTab, 'text');
            app.NhpdliuyEditField_4Label = uilabel(app.TchphnTab);
            app.NhpdliuyEditField_4Label.Text = 'Nhập dữ liệu y:';
            app.NhpdliuyEditField_4 = uieditfield(app.TchphnTab, 'text');
            app.NhphmsEditField_2Label = uilabel(app.TchphnTab);
            app.NhphmsEditField_2Label.Text = 'Nhập hàm số:';
            app.NhphmsEditField_2 = uieditfield(app.TchphnTab, 'text');
            app.PhngphpTchphnDropDownLabel = uilabel(app.TchphnTab);
            app.PhngphpTchphnDropDownLabel.Text = 'Phương pháp Tích phân:';
            app.PhngphpTchphnDropDown = uidropdown(app.TchphnTab);
            app.PhngphpTchphnDropDown.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.PhngphpTchphnDropDown.Value = 'Hình thang';
            app.KtquEditField_2Label = uilabel(app.TchphnTab);
            app.KtquEditField_2Label.Text = 'Kết quả:';
            app.KtquEditField_2 = uieditfield(app.TchphnTab, 'numeric');
            app.NhpcntnhtchphnEditFieldLabel = uilabel(app.TchphnTab);
            app.NhpcntnhtchphnEditFieldLabel.Text = 'Nhập cận để tính tích phân:';
            app.NhpcntnhtchphnEditField = uieditfield(app.TchphnTab, 'text');
            app.NhpNEditFieldLabel = uilabel(app.TchphnTab);
            app.NhpNEditFieldLabel.Text = 'Nhập N:';
            app.NhpNEditField = uieditfield(app.TchphnTab, 'text');
            app.GiithiunhmTab = uitab(app.TabGroup);
            app.GiithiunhmTab.Title = 'Giới thiệu nhóm';
            app.GiithiunhmTextAreaLabel = uilabel(app.GiithiunhmTab);
            app.GiithiunhmTextAreaLabel.Text = 'Giới thiệu nhóm:';
            app.GiithiunhmTextArea = uitextarea(app.GiithiunhmTab);
            app.GiithiuthnhvinTextAreaLabel = uilabel(app.GiithiunhmTab);
            app.GiithiuthnhvinTextAreaLabel.Text = 'Giới thiệu thành viên:';
            app.GiithiuthnhvinTextArea = uitextarea(app.GiithiunhmTab);
            app.NhimvTextAreaLabel = uilabel(app.GiithiunhmTab);
            app.NhimvTextAreaLabel.Text = 'Nhiệm vụ:';
            app.NhimvTextArea = uitextarea(app.GiithiunhmTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HuyHoang_Dec9_HoiQuy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end