classdef BaiTapNhom < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        NghimTab                      matlab.ui.container.Tab
        PhngtrnhLabel                 matlab.ui.control.Label
        fpEditField                   matlab.ui.control.EditField
        fpEditFieldLabel              matlab.ui.control.Label
        SlnlpEditField_2              matlab.ui.control.NumericEditField
        SlnlpEditField_2Label         matlab.ui.control.Label
        SaisEditField_2               matlab.ui.control.NumericEditField
        SaisEditField_2Label          matlab.ui.control.Label
        ResetButton                   matlab.ui.control.Button
        TnhtonButton                  matlab.ui.control.Button
        KtqunghimEditField            matlab.ui.control.NumericEditField
        KtqunghimEditFieldLabel       matlab.ui.control.Label
        PhngphpDropDown               matlab.ui.control.DropDown
        PhngphpDropDownLabel          matlab.ui.control.Label
        nEditField                    matlab.ui.control.NumericEditField
        nEditFieldLabel               matlab.ui.control.Label
        KhongphnlyEditField           matlab.ui.control.NumericEditField
        KhongphnlyEditFieldLabel      matlab.ui.control.Label
        PhuongTrinh                   matlab.ui.control.EditField
        Image                         matlab.ui.control.Image
        UIAxes                        matlab.ui.control.UIAxes
        NisuyTab                      matlab.ui.container.Tab
        ResetButton_2                 matlab.ui.control.Button
        KtqunisuyEditField            matlab.ui.control.NumericEditField
        KtqunisuyEditFieldLabel       matlab.ui.control.Label
        KtquathcnisuyEditField        matlab.ui.control.NumericEditField
        KtquathcnisuyEditFieldLabel   matlab.ui.control.Label
        NhpgitrcnnisuyEditField       matlab.ui.control.EditField
        NhpgitrcnnisuyEditFieldLabel  matlab.ui.control.Label
        PhngphpDropDown_2             matlab.ui.control.DropDown
        PhngphpDropDown_2Label        matlab.ui.control.Label
        NhpdliuyEditField             matlab.ui.control.EditField
        NhpdliuyEditFieldLabel        matlab.ui.control.Label
        NhpdliuxEditField             matlab.ui.control.EditField
        NhpdliuxEditFieldLabel        matlab.ui.control.Label
        UIAxes_2                      matlab.ui.control.UIAxes
        HiquyTab                      matlab.ui.container.Tab
        NhpgitrcndonEditField         matlab.ui.control.NumericEditField
        NhpgitrcndonEditField_2Label  matlab.ui.control.Label
        KtquphngtrnhhiquyEditField    matlab.ui.control.EditField
        KtquphngtrnhhiquyEditField_2Label_2  matlab.ui.control.Label
        KtqudonEditField              matlab.ui.control.NumericEditField
        KtqudonEditField_2Label       matlab.ui.control.Label
        TnhtonButton_2                matlab.ui.control.Button
        PhngphpDropDown_3             matlab.ui.control.DropDown
        PhngphpDropDown_3Label        matlab.ui.control.Label
        NhpdliuyEditField_2           matlab.ui.control.EditField
        NhpdliuyEditField_2Label      matlab.ui.control.Label
        NhpdliuxEditField_2           matlab.ui.control.EditField
        NhpdliuxEditField_2Label      matlab.ui.control.Label
        ResetButton_3                 matlab.ui.control.Button
        Image3                        matlab.ui.control.Image
        UIAxes_3                      matlab.ui.control.UIAxes
        ohmTab                        matlab.ui.container.Tab
        h_daoham                      matlab.ui.control.NumericEditField
        NhpbcnhyhEditFieldLabel       matlab.ui.control.Label
        Y_Du_doan_Dao_ham             matlab.ui.control.EditField
        KtquEditField_3Label          matlab.ui.control.Label
        X_Du_doan_Dao_ham             matlab.ui.control.EditField
        NhapgiatrixcantinhEditFieldLabel  matlab.ui.control.Label
        Tinh_Dao_ham                  matlab.ui.control.Button
        Cachnhap2                     matlab.ui.control.CheckBox
        Cachnhap1                     matlab.ui.control.CheckBox
        PP_Dao_ham                    matlab.ui.control.DropDown
        PhngphpohmDropDownLabel       matlab.ui.control.Label
        Saiso_daoham                  matlab.ui.control.DropDown
        GitrsaisDropDownLabel         matlab.ui.control.Label
        Ham_so_Dao_ham                matlab.ui.control.EditField
        NhphmsEditFieldLabel          matlab.ui.control.Label
        HocLabel                      matlab.ui.control.Label
        Data_y_Dao_ham                matlab.ui.control.EditField
        NhpdliuyEditField_3Label      matlab.ui.control.Label
        Data_x_Dao_ham                matlab.ui.control.EditField
        NhpdliuxEditField_3Label      matlab.ui.control.Label
        TchphnTab                     matlab.ui.container.Tab
        NhpNEditField                 matlab.ui.control.EditField
        NhpNEditFieldLabel            matlab.ui.control.Label
        NhpcntnhtchphnEditField       matlab.ui.control.EditField
        NhpcntnhtchphnEditFieldLabel  matlab.ui.control.Label
        KtquEditField_2               matlab.ui.control.NumericEditField
        KtquEditField_2Label          matlab.ui.control.Label
        PhngphpTchphnDropDown         matlab.ui.control.DropDown
        PhngphpTchphnDropDownLabel    matlab.ui.control.Label
        NhphmsEditField_2             matlab.ui.control.EditField
        NhphmsEditField_2Label        matlab.ui.control.Label
        NhpdliuyEditField_4           matlab.ui.control.EditField
        NhpdliuyEditField_4Label      matlab.ui.control.Label
        NhpdliuxEditField_4           matlab.ui.control.EditField
        NhpdliuxEditField_4Label      matlab.ui.control.Label
        HocLabel_2                    matlab.ui.control.Label
        GiithiunhmTab                 matlab.ui.container.Tab
        NhimvTextArea                 matlab.ui.control.TextArea
        NhimvTextAreaLabel            matlab.ui.control.Label
        GiithiunhmTextArea            matlab.ui.control.TextArea
        GiithiunhmTextAreaLabel       matlab.ui.control.Label
        GiithiuthnhvinTextArea        matlab.ui.control.TextArea
        GiithiuthnhvinTextAreaLabel   matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        function [A1, A0] = hoiQuyTinhToan(app, X, Y, n)
            sumX=0; sumY=0; sumXY=0; sumX2=0;
            for i = 1:n
                sumX = sumX + X(i);
                sumY = sumY + Y(i);
                sumXY = sumXY + X(i)*Y(i);
                sumX2 = sumX2 + X(i)*X(i);
            end
            Xm = sumX / n;
            Ym = sumY / n;
            A1 = (n*sumXY - sumX*sumY) / (n*sumX2 - sumX*sumX);
            A0 = Ym - A1*Xm;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TnhtonButton
        function TnhtonButtonPushed(app, event)
            fun = app.PhuongTrinh.Value;
            fp = app.fpEditField.Value;
            a = app.KhongphnlyEditField.Value;
            b = app.nEditField.Value;
            method_1 = app.PhngphpDropDown.Value;
            method_1 = string(method_1);
            es = app.SaisEditField_2.Value; %Sai số cho trước
            max = app.SlnlpEditField_2.Value;
            x = -10:0.0001:10;
            n = 0;
            switch method_1
                case {'Chia đôi'}
                    fx = str2func(['@(x)',fun]);
                    y = fx(x);
                    while n < max
                        n = n+1;
                        c = (a+b)/2;
                        fafc = fx(a)*fx(c);
                        if fafc < 0
                            b = c;
                        else
                            a = c;
                        end
                        ea = abs(b - a);
                        if (fafc == 0)
                            ea=0;
                        end
                        if ea < es
                            break
                        end
                    end
                plot(app.UIAxes,x,y);
                app.KtqunghimEditField.Value = c;
                case {'Lặp'} 
                    fx = str2func(['@(x)',fun]);
                    y = fx(x);
                    fp = str2func(['@(x)',fp]);
                    c = (a+b)/2;
                    if fx(a) * fx(c) < 0
                        x0 = b;
                    else
                        x0 = a;
                    end
                    while n < max
                        n = n+1;
                        x1 = fp(x0);
                        ea = abs(x1 - x0);
                        if ea < es
                            break;
                        end
                        x0 = x1;
                    end
                    plot(app.UIAxes,x,y);
                    app.KtqunghimEditField.Value = x1;
                case {'Newton'}
                    fx = str2sym(fun);
                    fp = diff(fx);
                    fpp = diff(fp);
                    fp = matlabFunction(fp);
                    fpp = matlabFunction(fpp);
                    fx = matlabFunction(fx);
                    n = 0;
                    if fx(a) * fpp(a) > 0
                        x0 = b;
                    else
                        x0 = a;
                    end
                    while n < max
                        n = n+1;
                        x1 = x0 - (fx(x0)/fp(x0));
                        ea = abs(x1 - x0);
                        if ea < es
                            break;
                        end
                        x0 = x1;
                    end
                    y = fx(x);
                    plot(app.UIAxes,x,y);
                    app.KtqunghimEditField.Value = x1;
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.KhongphnlyEditField.Value = 0;
            app.nEditField.Value = 0;
            app.PhuongTrinh.Value = '';
            app.fpEditField.Value = '';
            app.SaisEditField_2.Value = 0;
            app.SlnlpEditField_2.Value = 0;
            app.KtqunghimEditField.Value = 0;
            plot(app.UIAxes,0,0);
        end

        % Button pushed function: TnhtonButton_2
        function TnhtonButton_2Pushed(app, event)
            % Lấy dữ liệu từ client
            x_text = app.NhpdliuxEditField_2.Value;
            y_text = app.NhpdliuyEditField_2.Value;
            P_text = app.PhngphpDropDown_3.Value;
            x_find = app.NhpgitrcndonEditField.Value;
            
            % Chuyển đổi và khai báo
            x = str2num(x_text);
            y = str2num(y_text);
            n = length(x);
            if(length(x) ~= length(y))
                ResetButton_3Pushed(app, event);
                app.KtquphngtrnhhiquyEditField.Value = 'Lỗi chiều dài chuỗi x khác y';
                return;
            end
       
            % Tính toán
            if(isequal(P_text, 'P(x) = ax + b'))
                % Data for test
                % x = [6 7 11 15 17 21 23 29 29 37 39]
                % y = [29 21 29 14 21 15 7 7 13 0 3]
                X = x; Y = y;
                [A1, A0] = hoiQuyTinhToan(app, X, Y, n);
                a = A1; b = A0;
                
                app.KtquphngtrnhhiquyEditField.Value = [num2str(a) 'x + ' num2str(b)];
                app.KtqudonEditField.Value = a*x_find + b;
                cla(app.UIAxes_3);
                hold(app.UIAxes_3, 'on');
                plot(app.UIAxes_3, x, y, 'o');
                plot(app.UIAxes_3, x, a*x + b);
                legend(app.UIAxes_3, 'f(xi)', 'P(x)');
                hold(app.UIAxes_3, 'off');  
            elseif(isequal(P_text, 'P(x) = ax^b'))
                % Data for test
                % [1 2 3 4 5]
                % [0.5 1.7 3.4 5.7 8.4]
                X = log10(x); Y = log10(y);
                [A1, A0] = hoiQuyTinhToan(app, X, Y, n);
                a = 10.^A0; b = A1;
                
                app.KtquphngtrnhhiquyEditField.Value = [num2str(a) 'x^' num2str(b)];
                app.KtqudonEditField.Value = a * x_find.^b;
                cla(app.UIAxes_3);
                hold(app.UIAxes_3, 'on');
                plot(app.UIAxes_3, x, y, 'o');
                plot(app.UIAxes_3, x, a * x.^b);
                legend(app.UIAxes_3, 'f(xi)', 'P(x)');
                hold(app.UIAxes_3, 'off');
            elseif(isequal(P_text, 'P(x) = aln(x) + b'))
                % Data for test
                % [0.25 2.0 4.0 7.0 10.1]
                % [0 4.7 6.4 7.7 8.6]
                X = log(x); Y = y;
                [A1, A0] = hoiQuyTinhToan(app, X, Y, n);
                a = A1; b = A0;
                app.KtquphngtrnhhiquyEditField.Value = [num2str(a) 'ln(x) + ' num2str(b)];
                app.KtqudonEditField.Value = a*log(x_find) + b;
                cla(app.UIAxes_3);
                hold(app.UIAxes_3, 'on');
                plot(app.UIAxes_3, x, y, 'o');
                plot(app.UIAxes_3, x, a*log(x) + b);
                legend(app.UIAxes_3, 'f(xi)', 'P(x)');
                hold(app.UIAxes_3, 'off');
            end
        end

        % Button pushed function: ResetButton_3
        function ResetButton_3Pushed(app, event)
            app.NhpdliuxEditField_2.Value = '';
            app.NhpdliuyEditField_2.Value = '';
            app.PhngphpDropDown_3.Value = 'P(x) = ax + b';
            app.NhpgitrcndonEditField.Value = 0;
            app.KtquphngtrnhhiquyEditField.Value = '0';
            app.KtqudonEditField.Value = 0;
            cla(app.UIAxes_3);
        end

        % Value changed function: Cachnhap1
        function Cachnhap1ValueChanged(app, event)
            value = app.Cachnhap1.Value;
            app.Data_y_Dao_ham.Editable = value;
            app.Data_x_Dao_ham.Editable = value;
        end

        % Value changed function: Cachnhap2
        function Cachnhap2ValueChanged(app, event)
            value = app.Cachnhap2.Value;
            app.Ham_so_Dao_ham.Editable = value;
        end

        % Button pushed function: Tinh_Dao_ham
        function Tinh_Dao_hamButtonPushed(app, event)
            x = str2num(app.Data_x_Dao_ham.Value);
            y = str2num(app.Data_y_Dao_ham.Value);
            s = app.Saiso_daoham.Value; % O(h) và O(h^2)
            x_0 = str2num(app.X_Du_doan_Dao_ham.Value);
            fx = app.Ham_so_Dao_ham.Value;
            h = app.h_daoham.Value;
            
            if strcmp(s, 'O(h)')
                s = 1; % O(h)
            else
                s = 2; % O(h^2)
            end
            
            try
                if app.Cachnhap1.Value
                    if numel(x) ~= numel(y)
                        uialert(app.UIFigure,"y và x nên cùng kích thước", "Thông Báo", "Icon",'error');
                        return
                    end
                    switch app.PP_Dao_ham.Value
                        case "Xấp xỉ lùi"
                            app.Y_Du_doan_Dao_ham.Value = num2str(DH_lui_xy(x,y,x_0,h,s,app));
                        case "Xấp xỉ tiến"
                            app.Y_Du_doan_Dao_ham.Value = num2str(DH_tien_xy(x,y,x_0,h,s,app));
                        case "Trung tâm"
                            app.Y_Du_doan_Dao_ham.Value = num2str(DH_tt_xy(x,y,x_0,h,2,app));
                    end
                end
                if app.Cachnhap2.Value
                    switch app.PP_Dao_ham.Value
                        case "Xấp xỉ lùi"
                            app.Y_Du_doan_Dao_ham.Value = num2str(DH_lui_ham(char(fx),x_0,h,s));
                        case "Xấp xỉ tiến"
                            app.Y_Du_doan_Dao_ham.Value = num2str(DH_tien_ham(char(fx),x_0,h,s));
                        case "Trung tâm"
                            app.Y_Du_doan_Dao_ham.Value = num2str(DH_tt_ham(char(fx),x_0,h,2));
                    end
                end
            catch
             
                return
            end
            pause(0.100);
            
            if ~isempty(app.Y_Du_doan_Dao_ham.Value)
                uialert(app.UIFigure,"Successful","Thông Báo","Icon","success");
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghimTab = uitab(app.TabGroup);
            app.NghimTab.Title = 'Nghiệm';
            app.UIAxes = uiaxes(app.NghimTab);
            title(app.UIAxes, 'Đồ thị Hàm số')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.NghimTab);
            app.Image.ImageSource = 'E:\Matlab\File MATLAB\Thực hành phương pháp tính\Duykha5.png';
            app.PhuongTrinh = uieditfield(app.NghimTab, 'text');
            app.KhongphnlyEditFieldLabel = uilabel(app.NghimTab);
            app.KhongphnlyEditFieldLabel.Text = 'Khoảng phân ly:';
            app.KhongphnlyEditField = uieditfield(app.NghimTab, 'numeric');
            app.nEditFieldLabel = uilabel(app.NghimTab);
            app.nEditFieldLabel.Text = 'đến';
            app.nEditField = uieditfield(app.NghimTab, 'numeric');
            app.PhngphpDropDownLabel = uilabel(app.NghimTab);
            app.PhngphpDropDownLabel.Text = 'Phương pháp:';
            app.PhngphpDropDown = uidropdown(app.NghimTab);
            app.PhngphpDropDown.Items = {'Chia đôi', 'Lặp', 'Newton'};
            app.PhngphpDropDown.Value = 'Chia đôi';
            app.KtqunghimEditFieldLabel = uilabel(app.NghimTab);
            app.KtqunghimEditFieldLabel.Text = 'Kết quả nghiệm:';
            app.KtqunghimEditField = uieditfield(app.NghimTab, 'numeric');
            app.TnhtonButton = uibutton(app.NghimTab, 'push');
            app.TnhtonButton.ButtonPushedFcn = createCallbackFcn(app, @TnhtonButtonPushed, true);
            app.TnhtonButton.Text = 'Tính toán';
            app.ResetButton = uibutton(app.NghimTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SaisEditField_2Label = uilabel(app.NghimTab);
            app.SaisEditField_2Label.Text = 'Sai số:';
            app.SaisEditField_2 = uieditfield(app.NghimTab, 'numeric');
            app.SlnlpEditField_2Label = uilabel(app.NghimTab);
            app.SlnlpEditField_2Label.Text = 'Số lần lặp:';
            app.SlnlpEditField_2 = uieditfield(app.NghimTab, 'numeric');
            app.fpEditFieldLabel = uilabel(app.NghimTab);
            app.fpEditFieldLabel.Text = 'fp:';
            app.fpEditField = uieditfield(app.NghimTab, 'text');
            app.PhngtrnhLabel = uilabel(app.NghimTab);
            app.PhngtrnhLabel.Text = 'Phương trình:';
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.UIAxes_2 = uiaxes(app.NisuyTab);
            title(app.UIAxes_2, 'Đồ thị số nội suy và dữ liệu thực ')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.NhpdliuxEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x:';
            app.NhpdliuxEditField = uieditfield(app.NisuyTab, 'text');
            app.NhpdliuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y:';
            app.NhpdliuyEditField = uieditfield(app.NisuyTab, 'text');
            app.PhngphpDropDown_2Label = uilabel(app.NisuyTab);
            app.PhngphpDropDown_2Label.Text = 'Phương pháp:';
            app.PhngphpDropDown_2 = uidropdown(app.NisuyTab);
            app.PhngphpDropDown_2.Items = {'Newton', 'Lagrange'};
            app.PhngphpDropDown_2.Value = 'Newton';
            app.NhpgitrcnnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpgitrcnnisuyEditFieldLabel.Text = 'Nhập giá trị cần nội suy';
            app.NhpgitrcnnisuyEditField = uieditfield(app.NisuyTab, 'text');
            app.KtquathcnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtquathcnisuyEditFieldLabel.Text = 'Kết quả đa thức nội suy:';
            app.KtquathcnisuyEditField = uieditfield(app.NisuyTab, 'numeric');
            app.KtqunisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtqunisuyEditFieldLabel.Text = 'Kết quả nội suy:';
            app.KtqunisuyEditField = uieditfield(app.NisuyTab, 'numeric');
            app.ResetButton_2 = uibutton(app.NisuyTab, 'push');
            app.ResetButton_2.Text = 'Reset';
            app.HiquyTab = uitab(app.TabGroup);
            app.HiquyTab.Title = 'Hồi quy';
            app.UIAxes_3 = uiaxes(app.HiquyTab);
            title(app.UIAxes_3, 'Đồ thị số hồi quy và dữ liệu thực ')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.Image3 = uiimage(app.HiquyTab);
            app.Image3.ImageSource = 'E:\Matlab\File MATLAB\Thực hành phương pháp tính\HuyHoang1.png';
            app.ResetButton_3 = uibutton(app.HiquyTab, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_3Pushed, true);
            app.ResetButton_3.Text = 'Reset';
            app.NhpdliuxEditField_2Label = uilabel(app.HiquyTab);
            app.NhpdliuxEditField_2Label.Text = 'Nhập dữ liệu x:';
            app.NhpdliuxEditField_2 = uieditfield(app.HiquyTab, 'text');
            app.NhpdliuyEditField_2Label = uilabel(app.HiquyTab);
            app.NhpdliuyEditField_2Label.Text = 'Nhập dữ liệu y:';
            app.NhpdliuyEditField_2 = uieditfield(app.HiquyTab, 'text');
            app.PhngphpDropDown_3Label = uilabel(app.HiquyTab);
            app.PhngphpDropDown_3Label.Text = 'Phương pháp:';
            app.PhngphpDropDown_3 = uidropdown(app.HiquyTab);
            app.PhngphpDropDown_3.Items = {'P(x) = ax + b', 'P(x) = ax^b', 'P(x) = aln(x) + b'};
            app.PhngphpDropDown_3.Value = 'P(x) = ax + b';
            app.TnhtonButton_2 = uibutton(app.HiquyTab, 'push');
            app.TnhtonButton_2.ButtonPushedFcn = createCallbackFcn(app, @TnhtonButton_2Pushed, true);
            app.TnhtonButton_2.Text = 'Tính toán';
            app.KtqudonEditField_2Label = uilabel(app.HiquyTab);
            app.KtqudonEditField_2Label.Text = 'Kết quả dự đoán:';
            app.KtqudonEditField = uieditfield(app.HiquyTab, 'numeric');
            app.KtquphngtrnhhiquyEditField_2Label_2 = uilabel(app.HiquyTab);
            app.KtquphngtrnhhiquyEditField_2Label_2.Text = 'Kết quả phương trình hồi quy:';
            app.KtquphngtrnhhiquyEditField = uieditfield(app.HiquyTab, 'text');
            app.KtquphngtrnhhiquyEditField.Value = '0';
            app.NhpgitrcndonEditField_2Label = uilabel(app.HiquyTab);
            app.NhpgitrcndonEditField_2Label.Text = 'Nhập giá trị cần dự đoán:';
            app.NhpgitrcndonEditField = uieditfield(app.HiquyTab, 'numeric');
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.NhpdliuxEditField_3Label = uilabel(app.ohmTab);
            app.NhpdliuxEditField_3Label.Text = 'Nhập dữ liệu x:';
            app.Data_x_Dao_ham = uieditfield(app.ohmTab, 'text');
            app.NhpdliuyEditField_3Label = uilabel(app.ohmTab);
            app.NhpdliuyEditField_3Label.Text = 'Nhập dữ liệu y:';
            app.Data_y_Dao_ham = uieditfield(app.ohmTab, 'text');
            app.HocLabel = uilabel(app.ohmTab);
            app.HocLabel.Text = 'Hoặc';
            app.NhphmsEditFieldLabel = uilabel(app.ohmTab);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số:';
            app.Ham_so_Dao_ham = uieditfield(app.ohmTab, 'text');
            app.GitrsaisDropDownLabel = uilabel(app.ohmTab);
            app.GitrsaisDropDownLabel.Text = 'Giá trị sai số:';
            app.Saiso_daoham = uidropdown(app.ohmTab);
            app.Saiso_daoham.Items = {'O(h)', 'O(h^2)'};
            app.Saiso_daoham.Value = 'O(h)';
            app.PhngphpohmDropDownLabel = uilabel(app.ohmTab);
            app.PhngphpohmDropDownLabel.Text = 'Phương pháp đạo hàm:';
            app.PP_Dao_ham = uidropdown(app.ohmTab);
            app.PP_Dao_ham.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Trung tâm'};
            app.PP_Dao_ham.Value = 'Xấp xỉ tiến';
            app.Cachnhap1 = uicheckbox(app.ohmTab);
            app.Cachnhap1.ValueChangedFcn = createCallbackFcn(app, @Cachnhap1ValueChanged, true);
            app.Cachnhap1.Text = 'Phương án 1';
            app.Cachnhap2 = uicheckbox(app.ohmTab);
            app.Cachnhap2.ValueChangedFcn = createCallbackFcn(app, @Cachnhap2ValueChanged, true);
            app.Cachnhap2.Text = 'Phương án 2';
            app.Tinh_Dao_ham = uibutton(app.ohmTab, 'push');
            app.Tinh_Dao_ham.ButtonPushedFcn = createCallbackFcn(app, @Tinh_Dao_hamButtonPushed, true);
            app.Tinh_Dao_ham.Text = 'Thực hiện';
            app.NhapgiatrixcantinhEditFieldLabel = uilabel(app.ohmTab);
            app.NhapgiatrixcantinhEditFieldLabel.Text = 'Nhap gia tri x can tinh';
            app.X_Du_doan_Dao_ham = uieditfield(app.ohmTab, 'text');
            app.KtquEditField_3Label = uilabel(app.ohmTab);
            app.KtquEditField_3Label.Text = 'Kết quả';
            app.Y_Du_doan_Dao_ham = uieditfield(app.ohmTab, 'text');
            app.NhpbcnhyhEditFieldLabel = uilabel(app.ohmTab);
            app.NhpbcnhyhEditFieldLabel.Text = 'Nhập bước nhảy h';
            app.h_daoham = uieditfield(app.ohmTab, 'numeric');
            app.TchphnTab = uitab(app.TabGroup);
            app.TchphnTab.Title = 'Tích phân';
            app.HocLabel_2 = uilabel(app.TchphnTab);
            app.HocLabel_2.Text = 'Hoặc';
            app.NhpdliuxEditField_4Label = uilabel(app.TchphnTab);
            app.NhpdliuxEditField_4Label.Text = 'Nhập dữ liệu x:';
            app.NhpdliuxEditField_4 = uieditfield(app.TchphnTab, 'text');
            app.NhpdliuyEditField_4Label = uilabel(app.TchphnTab);
            app.NhpdliuyEditField_4Label.Text = 'Nhập dữ liệu y:';
            app.NhpdliuyEditField_4 = uieditfield(app.TchphnTab, 'text');
            app.NhphmsEditField_2Label = uilabel(app.TchphnTab);
            app.NhphmsEditField_2Label.Text = 'Nhập hàm số:';
            app.NhphmsEditField_2 = uieditfield(app.TchphnTab, 'text');
            app.PhngphpTchphnDropDownLabel = uilabel(app.TchphnTab);
            app.PhngphpTchphnDropDownLabel.Text = 'Phương pháp Tích phân:';
            app.PhngphpTchphnDropDown = uidropdown(app.TchphnTab);
            app.PhngphpTchphnDropDown.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.PhngphpTchphnDropDown.Value = 'Hình thang';
            app.KtquEditField_2Label = uilabel(app.TchphnTab);
            app.KtquEditField_2Label.Text = 'Kết quả:';
            app.KtquEditField_2 = uieditfield(app.TchphnTab, 'numeric');
            app.NhpcntnhtchphnEditFieldLabel = uilabel(app.TchphnTab);
            app.NhpcntnhtchphnEditFieldLabel.Text = 'Nhập cận để tính tích phân:';
            app.NhpcntnhtchphnEditField = uieditfield(app.TchphnTab, 'text');
            app.NhpNEditFieldLabel = uilabel(app.TchphnTab);
            app.NhpNEditFieldLabel.Text = 'Nhập N:';
            app.NhpNEditField = uieditfield(app.TchphnTab, 'text');
            app.GiithiunhmTab = uitab(app.TabGroup);
            app.GiithiunhmTab.Title = 'Giới thiệu nhóm';
            app.Image2 = uiimage(app.GiithiunhmTab);
            app.Image2.ImageSource = 'E:\Matlab\File MATLAB\Thực hành phương pháp tính\SL-022722-48830-13.jpg';
            app.GiithiuthnhvinTextAreaLabel = uilabel(app.GiithiunhmTab);
            app.GiithiuthnhvinTextAreaLabel.Text = 'Giới thiệu thành viên:';
            app.GiithiuthnhvinTextArea = uitextarea(app.GiithiunhmTab);
            app.GiithiuthnhvinTextArea.Value = {'Bùi Thành Đạt - 21207001'; 'Nguyễn Thị Trà Chi - 21207124'; 'Lê Quang Đăng - 21207130'; 'Trịnh Huy Hoàng - 21207156'; 'Phạm Đức Hùng - 21207158'; 'Thái Ngọc Duy Kha - 21207167'};
            app.GiithiunhmTextAreaLabel = uilabel(app.GiithiunhmTab);
            app.GiithiunhmTextAreaLabel.Text = 'Giới thiệu nhóm:';
            app.GiithiunhmTextArea = uitextarea(app.GiithiunhmTab);
            app.NhimvTextAreaLabel = uilabel(app.GiithiunhmTab);
            app.NhimvTextAreaLabel.Text = 'Nhiệm vụ:';
            app.NhimvTextArea = uitextarea(app.GiithiunhmTab);
            app.NhimvTextArea.Value = {'Code phần Nghiệm: Thái Ngọc Duy Kha'; 'Code phần Nội Suy: Nguyễn Thị Trà Chi'; 'Code Phần Hồi quy: Trịnh Huy Hoàng'; 'Code phần Đạo hàm: Lê Quang Đăng'; 'Code phần Tích phân: Bùi Thành Đạt'; 'Thiết kế giao diện và App: Phạm Đức Hùng'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BaiTapNhom

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end