classdef ThanhDat_Dec09_TichPhan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        NghimTab                      matlab.ui.container.Tab
        ResetButton                   matlab.ui.control.Button
        TnhtonButton                  matlab.ui.control.Button
        KtqunghimEditField            matlab.ui.control.NumericEditField
        KtqunghimEditFieldLabel       matlab.ui.control.Label
        SlnlpEditField                matlab.ui.control.EditField
        SlnlpLabel                    matlab.ui.control.Label
        PhngphpDropDown               matlab.ui.control.DropDown
        PhngphpDropDownLabel          matlab.ui.control.Label
        SaisEditField                 matlab.ui.control.EditField
        SaisEditFieldLabel            matlab.ui.control.Label
        nEditField                    matlab.ui.control.NumericEditField
        nEditFieldLabel               matlab.ui.control.Label
        KhongphnlyEditField           matlab.ui.control.NumericEditField
        KhongphnlyEditFieldLabel      matlab.ui.control.Label
        PhngtrnhEditField             matlab.ui.control.EditField
        PhngtrnhEditFieldLabel        matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        NisuyTab                      matlab.ui.container.Tab
        ResetButton_2                 matlab.ui.control.Button
        KtqunisuyEditField            matlab.ui.control.NumericEditField
        KtqunisuyEditFieldLabel       matlab.ui.control.Label
        KtquathcnisuyEditField        matlab.ui.control.NumericEditField
        KtquathcnisuyEditFieldLabel   matlab.ui.control.Label
        NhpgitrcnnisuyEditField       matlab.ui.control.EditField
        NhpgitrcnnisuyEditFieldLabel  matlab.ui.control.Label
        PhngphpDropDown_2             matlab.ui.control.DropDown
        PhngphpDropDown_2Label        matlab.ui.control.Label
        NhpdliuyEditField             matlab.ui.control.EditField
        NhpdliuyEditFieldLabel        matlab.ui.control.Label
        NhpdliuxEditField             matlab.ui.control.EditField
        NhpdliuxEditFieldLabel        matlab.ui.control.Label
        UIAxes_2                      matlab.ui.control.UIAxes
        HiquyTab                      matlab.ui.container.Tab
        KtqudonEditField              matlab.ui.control.NumericEditField
        KtqudonEditFieldLabel         matlab.ui.control.Label
        KtquphngtrnhhiquyEditField    matlab.ui.control.NumericEditField
        KtquphngtrnhhiquyLabel        matlab.ui.control.Label
        NhpgitrcndonEditField         matlab.ui.control.EditField
        NhpgitrcndonEditFieldLabel    matlab.ui.control.Label
        PhngphpDropDown_3             matlab.ui.control.DropDown
        PhngphpDropDown_3Label        matlab.ui.control.Label
        NhpdliuyEditField_2           matlab.ui.control.EditField
        NhpdliuyEditField_2Label      matlab.ui.control.Label
        NhpdliuxEditField_2           matlab.ui.control.EditField
        NhpdliuxEditField_2Label      matlab.ui.control.Label
        ResetButton_3                 matlab.ui.control.Button
        UIAxes_3                      matlab.ui.control.UIAxes
        ohmTab                        matlab.ui.container.Tab
        KtquEditField                 matlab.ui.control.NumericEditField
        KtquEditFieldLabel            matlab.ui.control.Label
        PhngphpohmDropDown            matlab.ui.control.DropDown
        PhngphpohmDropDownLabel       matlab.ui.control.Label
        GitrsaisDropDown              matlab.ui.control.DropDown
        GitrsaisDropDownLabel         matlab.ui.control.Label
        NhpbchEditField               matlab.ui.control.EditField
        NhpbchEditFieldLabel          matlab.ui.control.Label
        NhphmsEditField               matlab.ui.control.EditField
        NhphmsEditFieldLabel          matlab.ui.control.Label
        HocLabel                      matlab.ui.control.Label
        NhpdliuyEditField_3           matlab.ui.control.EditField
        NhpdliuyEditField_3Label      matlab.ui.control.Label
        NhpdliuxEditField_3           matlab.ui.control.EditField
        NhpdliuxEditField_3Label      matlab.ui.control.Label
        TchphnTab                     matlab.ui.container.Tab
        InputMeth                     matlab.ui.control.Switch
        TP_Calc                       matlab.ui.control.Button
        TP_N                          matlab.ui.control.NumericEditField
        NhpNEditField_2Label          matlab.ui.control.Label
        TP_Can                        matlab.ui.control.EditField
        NhpcntnhtchphnEditFieldLabel  matlab.ui.control.Label
        TP_KQ                         matlab.ui.control.NumericEditField
        KtquEditField_2Label          matlab.ui.control.Label
        TP_PhuongPhap                 matlab.ui.control.DropDown
        PhngphpTchphnDropDownLabel    matlab.ui.control.Label
        TP_HamSo                      matlab.ui.control.EditField
        NhphmsEditField_2Label        matlab.ui.control.Label
        TP_Y_value                    matlab.ui.control.EditField
        NhpdliuyEditField_4Label      matlab.ui.control.Label
        TP_X_value                    matlab.ui.control.EditField
        NhpdliuxEditField_4Label      matlab.ui.control.Label
        HocLabel_2                    matlab.ui.control.Label
        GiithiunhmTab                 matlab.ui.container.Tab
        NhimvTextArea                 matlab.ui.control.TextArea
        NhimvTextAreaLabel            matlab.ui.control.Label
        GiithiuthnhvinTextArea        matlab.ui.control.TextArea
        GiithiuthnhvinTextAreaLabel   matlab.ui.control.Label
        GiithiunhmTextArea            matlab.ui.control.TextArea
        GiithiunhmTextAreaLabel       matlab.ui.control.Label
    end

    
    methods (Access = public)
        

        function Nhap_sai(app)
            uialert(app.UIFigure, 'Nhập Sai', 'Thông báo');
            pause(0.1);
        end

        function tp = TP_Simpson13_xy(~, x, y)
            % x, y la mang gia tri
            % a, b la can
            % N chia nho N: chan
            N = length(x) - 1;
            h = x(2) - x(1);
            i = 1:2:N-1;
            j = 2:2:N-2;
            tp = (h/3) * (y(1) + y(N + 1) + 4*sum(y(1 + i)) + 2*sum(y(1 + j)));
        end

        function I = TP_Simpson38_xy(~, x, y)
            N = length(x) - 1;
            h = x(2) - x(1);    
            I = y(1) + y(N + 1);
            for i = 1:(N - 1)
                if rem(i, 3) == 0 
                    I = I + 2 * y(1 + i);
                else
                    I = I + 3 * y(1 + i);
                end
            end
            I = I * 3 * (h / 8);
        end


        function S = TP_Hinhthang_Ham(~, f, a, b, N)
            fx = str2func(['@(x)', f]);
            h = (b - a)/N;
            S = h/2*(fx(a)+fx(b));
            for i = 1:N-1
                S = S + h*sum(fx(a+i*h));
            end
        end

        function tp = TP_Simpson13_Ham(~, f, a, b, N)
            syms g(x)
            g(x) = str2sym(char(f));
            if rem(N,2) ~= 0
                N = N + 1;
            end
            h = (b - a)/N;
            i = 1:2:N-1;
            j = 2:2:N-2;
            tp = (h/3) * (g(a) + g(b) + 4*sum(g(a + i*h)) + 2*sum(g(a + j*h)));
            tp = str2num(char(tp));
        end

        function I = TP_Simpson38_Ham(~, f, a, b, N)
            f = append('@(x)',f);
            fx = str2func(char(f));
            h = (b-a)/N;
            I = fx(a) + fx(b);
            for i = 1:(N-1)
                k = a + i*h;
                if rem(i,3) == 0
                    I = I + 2 * fx(k);
                else
                    I = I + 3 * fx(k);
                end
            end
            I = I * 3 * (h/8);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TP_Calc
        function TP_CalcPushed(app, event)
            try
                if app.InputMeth.Value == 0
                    x = str2num(app.TP_X_value.Value);
                    y = str2num(app.TP_Y_value.Value);
                    if numel(x) ~= numel(y)
                        uialert(app.UIFigure,"x, y phải cùng kích thước","Thông báo","Icon",'error');
                        return
                    end
                    switch app.TP_PhuongPhap.Value
                        case "Simpson 1/3"
                            app.TP_KQ.Value = TP_Simpson13_xy(app, x, y);
                        case "Simpson 3/8"
                            app.TP_KQ.Value = TP_Simpson38_xy(app, x, y);
                    end
                end
                
                if app.InputMeth.Value == 1
                    can = str2num(app.TP_Can.Value);
                    a = can(1);
                    b = can(end);
                    f = app.TP_HamSo.Value;
                    N = app.TP_N.Value;
                    switch app.TP_PhuongPhap.Value
                        case "Simpson 3/8"
                            app.TP_KQ.Value = TP_Simpson38_Ham(app, f, a, b, N);
                        case "Simpson 1/3"
                            app.TP_KQ.Value = TP_Simpson13_Ham(app, f, a, b, N);
                        case "Hình thang"
                            app.TP_KQ.Value = TP_Hinhthang_Ham(app, f, a, b, N);
                    end
                end
            catch
                app.TP_KQ.Value = 00000;
                Nhap_sai(app);
                return
            end
            uialert(app.UIFigure,"Đã tính xong","Thông Báo","Icon",'success');
        end

        % Value changed function: InputMeth
        function InputMethValueChanged(app, event)
        
            app.TP_X_value.Editable = ~app.InputMeth.Value;
            app.TP_Y_value.Editable = ~app.InputMeth.Value;
            app.TP_HamSo.Editable = app.InputMeth.Value;
            if (app.InputMeth.Value == 0)
                app.TP_PhuongPhap.Items = {'Simpson 1/3', 'Simpson 3/8'};
            else
                app.TP_PhuongPhap.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghimTab = uitab(app.TabGroup);
            app.NghimTab.Title = 'Nghiệm';
            app.UIAxes = uiaxes(app.NghimTab);
            title(app.UIAxes, 'Đồ thị Hàm số')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PhngtrnhEditFieldLabel = uilabel(app.NghimTab);
            app.PhngtrnhEditFieldLabel.Text = 'Phương trình:';
            app.PhngtrnhEditField = uieditfield(app.NghimTab, 'text');
            app.KhongphnlyEditFieldLabel = uilabel(app.NghimTab);
            app.KhongphnlyEditFieldLabel.Text = 'Khoảng phân ly:';
            app.KhongphnlyEditField = uieditfield(app.NghimTab, 'numeric');
            app.nEditFieldLabel = uilabel(app.NghimTab);
            app.nEditFieldLabel.Text = 'đến';
            app.nEditField = uieditfield(app.NghimTab, 'numeric');
            app.SaisEditFieldLabel = uilabel(app.NghimTab);
            app.SaisEditFieldLabel.Text = 'Sai số:';
            app.SaisEditField = uieditfield(app.NghimTab, 'text');
            app.SaisEditField.Value = '0';
            app.PhngphpDropDownLabel = uilabel(app.NghimTab);
            app.PhngphpDropDownLabel.Text = 'Phương pháp:';
            app.PhngphpDropDown = uidropdown(app.NghimTab);
            app.PhngphpDropDown.Items = {'Chia đôi', 'Lặp', 'Newton'};
            app.PhngphpDropDown.Value = 'Chia đôi';
            app.SlnlpLabel = uilabel(app.NghimTab);
            app.SlnlpLabel.Text = 'Số lần lặp:';
            app.SlnlpEditField = uieditfield(app.NghimTab, 'text');
            app.SlnlpEditField.Value = '0';
            app.KtqunghimEditFieldLabel = uilabel(app.NghimTab);
            app.KtqunghimEditFieldLabel.Text = 'Kết quả nghiệm:';
            app.KtqunghimEditField = uieditfield(app.NghimTab, 'numeric');
            app.TnhtonButton = uibutton(app.NghimTab, 'push');
            app.TnhtonButton.Text = 'Tính toán';
            app.ResetButton = uibutton(app.NghimTab, 'push');
            app.ResetButton.Text = 'Reset';
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.UIAxes_2 = uiaxes(app.NisuyTab);
            title(app.UIAxes_2, 'Đồ thị số nội suy và dữ liệu thực ')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.NhpdliuxEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x:';
            app.NhpdliuxEditField = uieditfield(app.NisuyTab, 'text');
            app.NhpdliuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y:';
            app.NhpdliuyEditField = uieditfield(app.NisuyTab, 'text');
            app.PhngphpDropDown_2Label = uilabel(app.NisuyTab);
            app.PhngphpDropDown_2Label.Text = 'Phương pháp:';
            app.PhngphpDropDown_2 = uidropdown(app.NisuyTab);
            app.PhngphpDropDown_2.Items = {'Newton', 'Lagrange'};
            app.PhngphpDropDown_2.Value = 'Newton';
            app.NhpgitrcnnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpgitrcnnisuyEditFieldLabel.Text = 'Nhập giá trị cần nội suy';
            app.NhpgitrcnnisuyEditField = uieditfield(app.NisuyTab, 'text');
            app.KtquathcnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtquathcnisuyEditFieldLabel.Text = 'Kết quả đa thức nội suy:';
            app.KtquathcnisuyEditField = uieditfield(app.NisuyTab, 'numeric');
            app.KtqunisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtqunisuyEditFieldLabel.Text = 'Kết quả nội suy:';
            app.KtqunisuyEditField = uieditfield(app.NisuyTab, 'numeric');
            app.ResetButton_2 = uibutton(app.NisuyTab, 'push');
            app.ResetButton_2.Text = 'Reset';
            app.HiquyTab = uitab(app.TabGroup);
            app.HiquyTab.Title = 'Hồi quy';
            app.UIAxes_3 = uiaxes(app.HiquyTab);
            title(app.UIAxes_3, 'Đồ thị số hồi quy và dữ liệu thực ')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.ResetButton_3 = uibutton(app.HiquyTab, 'push');
            app.ResetButton_3.Text = 'Reset';
            app.NhpdliuxEditField_2Label = uilabel(app.HiquyTab);
            app.NhpdliuxEditField_2Label.Text = 'Nhập dữ liệu x:';
            app.NhpdliuxEditField_2 = uieditfield(app.HiquyTab, 'text');
            app.NhpdliuyEditField_2Label = uilabel(app.HiquyTab);
            app.NhpdliuyEditField_2Label.Text = 'Nhập dữ liệu y:';
            app.NhpdliuyEditField_2 = uieditfield(app.HiquyTab, 'text');
            app.PhngphpDropDown_3Label = uilabel(app.HiquyTab);
            app.PhngphpDropDown_3Label.Text = 'Phương pháp:';
            app.PhngphpDropDown_3 = uidropdown(app.HiquyTab);
            app.PhngphpDropDown_3.Items = {'Tuyến tính', 'Hàm mũ', 'Logarit'};
            app.PhngphpDropDown_3.Value = 'Logarit';
            app.NhpgitrcndonEditFieldLabel = uilabel(app.HiquyTab);
            app.NhpgitrcndonEditFieldLabel.Text = 'Nhập giá trị cần dự đoán:';
            app.NhpgitrcndonEditField = uieditfield(app.HiquyTab, 'text');
            app.KtquphngtrnhhiquyLabel = uilabel(app.HiquyTab);
            app.KtquphngtrnhhiquyLabel.Text = 'Kết quả phương trình hồi quy:';
            app.KtquphngtrnhhiquyEditField = uieditfield(app.HiquyTab, 'numeric');
            app.KtqudonEditFieldLabel = uilabel(app.HiquyTab);
            app.KtqudonEditFieldLabel.Text = 'Kết quả dự đoán:';
            app.KtqudonEditField = uieditfield(app.HiquyTab, 'numeric');
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.NhpdliuxEditField_3Label = uilabel(app.ohmTab);
            app.NhpdliuxEditField_3Label.Text = 'Nhập dữ liệu x:';
            app.NhpdliuxEditField_3 = uieditfield(app.ohmTab, 'text');
            app.NhpdliuyEditField_3Label = uilabel(app.ohmTab);
            app.NhpdliuyEditField_3Label.Text = 'Nhập dữ liệu y:';
            app.NhpdliuyEditField_3 = uieditfield(app.ohmTab, 'text');
            app.HocLabel = uilabel(app.ohmTab);
            app.HocLabel.Text = 'Hoặc';
            app.NhphmsEditFieldLabel = uilabel(app.ohmTab);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số:';
            app.NhphmsEditField = uieditfield(app.ohmTab, 'text');
            app.NhpbchEditFieldLabel = uilabel(app.ohmTab);
            app.NhpbchEditFieldLabel.Text = 'Nhập bước h:';
            app.NhpbchEditField = uieditfield(app.ohmTab, 'text');
            app.GitrsaisDropDownLabel = uilabel(app.ohmTab);
            app.GitrsaisDropDownLabel.Text = 'Giá trị sai số:';
            app.GitrsaisDropDown = uidropdown(app.ohmTab);
            app.GitrsaisDropDown.Items = {'O(h)', 'O(h^2)'};
            app.GitrsaisDropDown.Value = 'O(h)';
            app.PhngphpohmDropDownLabel = uilabel(app.ohmTab);
            app.PhngphpohmDropDownLabel.Text = 'Phương pháp đạo hàm:';
            app.PhngphpohmDropDown = uidropdown(app.ohmTab);
            app.PhngphpohmDropDown.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Trung tâm'};
            app.PhngphpohmDropDown.Value = 'Xấp xỉ tiến';
            app.KtquEditFieldLabel = uilabel(app.ohmTab);
            app.KtquEditFieldLabel.Text = 'Kết quả:';
            app.KtquEditField = uieditfield(app.ohmTab, 'numeric');
            app.TchphnTab = uitab(app.TabGroup);
            app.TchphnTab.Title = 'Tích phân';
            app.HocLabel_2 = uilabel(app.TchphnTab);
            app.HocLabel_2.Text = 'Hoặc';
            app.NhpdliuxEditField_4Label = uilabel(app.TchphnTab);
            app.NhpdliuxEditField_4Label.Text = 'Nhập dữ liệu x:';
            app.TP_X_value = uieditfield(app.TchphnTab, 'text');
            app.NhpdliuyEditField_4Label = uilabel(app.TchphnTab);
            app.NhpdliuyEditField_4Label.Text = 'Nhập dữ liệu y:';
            app.TP_Y_value = uieditfield(app.TchphnTab, 'text');
            app.NhphmsEditField_2Label = uilabel(app.TchphnTab);
            app.NhphmsEditField_2Label.Text = 'Nhập hàm số:';
            app.TP_HamSo = uieditfield(app.TchphnTab, 'text');
            app.PhngphpTchphnDropDownLabel = uilabel(app.TchphnTab);
            app.PhngphpTchphnDropDownLabel.Text = 'Phương pháp Tích phân:';
            app.TP_PhuongPhap = uidropdown(app.TchphnTab);
            app.TP_PhuongPhap.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.TP_PhuongPhap.Value = 'Hình thang';
            app.KtquEditField_2Label = uilabel(app.TchphnTab);
            app.KtquEditField_2Label.Text = 'Kết quả:';
            app.TP_KQ = uieditfield(app.TchphnTab, 'numeric');
            app.NhpcntnhtchphnEditFieldLabel = uilabel(app.TchphnTab);
            app.NhpcntnhtchphnEditFieldLabel.Text = 'Nhập cận để tính tích phân:';
            app.TP_Can = uieditfield(app.TchphnTab, 'text');
            app.NhpNEditField_2Label = uilabel(app.TchphnTab);
            app.NhpNEditField_2Label.Text = 'Nhập N: ';
            app.TP_N = uieditfield(app.TchphnTab, 'numeric');
            app.TP_Calc = uibutton(app.TchphnTab, 'push');
            app.TP_Calc.ButtonPushedFcn = createCallbackFcn(app, @TP_CalcPushed, true);
            app.TP_Calc.Text = 'Reset';
            app.InputMeth = uiswitch(app.TchphnTab, 'slider');
            app.InputMeth.Items = {'Cách nhập 1', 'Cách nhập 2'};
            app.InputMeth.ItemsData = [0 1];
            app.InputMeth.ValueChangedFcn = createCallbackFcn(app, @InputMethValueChanged, true);
            app.InputMeth.Value = 0;
            app.GiithiunhmTab = uitab(app.TabGroup);
            app.GiithiunhmTab.Title = 'Giới thiệu nhóm';
            app.GiithiunhmTextAreaLabel = uilabel(app.GiithiunhmTab);
            app.GiithiunhmTextAreaLabel.Text = 'Giới thiệu nhóm:';
            app.GiithiunhmTextArea = uitextarea(app.GiithiunhmTab);
            app.GiithiuthnhvinTextAreaLabel = uilabel(app.GiithiunhmTab);
            app.GiithiuthnhvinTextAreaLabel.Text = 'Giới thiệu thành viên:';
            app.GiithiuthnhvinTextArea = uitextarea(app.GiithiunhmTab);
            app.NhimvTextAreaLabel = uilabel(app.GiithiunhmTab);
            app.NhimvTextAreaLabel.Text = 'Nhiệm vụ:';
            app.NhimvTextArea = uitextarea(app.GiithiunhmTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ThanhDat_Dec09_TichPhan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end