classdef BaiTapNhom < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        NghimTab                      matlab.ui.container.Tab
        ResetButton                   matlab.ui.control.Button
        TnhtonButton                  matlab.ui.control.Button
        KtqunghimEditField            matlab.ui.control.NumericEditField
        KtqunghimEditFieldLabel       matlab.ui.control.Label
        SlnlpEditField                matlab.ui.control.EditField
        SlnlpLabel                    matlab.ui.control.Label
        PhngphpDropDown               matlab.ui.control.DropDown
        PhngphpDropDownLabel          matlab.ui.control.Label
        SaisEditField                 matlab.ui.control.EditField
        SaisEditFieldLabel            matlab.ui.control.Label
        nEditField                    matlab.ui.control.NumericEditField
        nEditFieldLabel               matlab.ui.control.Label
        KhongphnlyEditField           matlab.ui.control.NumericEditField
        KhongphnlyEditFieldLabel      matlab.ui.control.Label
        PhngtrnhEditField             matlab.ui.control.EditField
        PhngtrnhEditFieldLabel        matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        NisuyTab                      matlab.ui.container.Tab
        KQ_DaThucNS                   matlab.ui.control.TextArea
        KtquathcnisuyTextAreaLabel    matlab.ui.control.Label
        KQ_NoiSuy                     matlab.ui.control.EditField
        KtqunisuyEditFieldLabel_2     matlab.ui.control.Label
        ResetButton_2                 matlab.ui.control.Button
        NoiSuy_Val                    matlab.ui.control.EditField
        NhpgitrcnnisuyEditFieldLabel  matlab.ui.control.Label
        NoiSuy_PP                     matlab.ui.control.DropDown
        PhngphpDropDown_2Label        matlab.ui.control.Label
        NoiSuy_y                      matlab.ui.control.EditField
        NhpdliuyEditFieldLabel        matlab.ui.control.Label
        NoiSuy_x                      matlab.ui.control.EditField
        NhpdliuxEditFieldLabel        matlab.ui.control.Label
        NoiSuy_Fig                    matlab.ui.control.UIAxes
        HiquyTab                      matlab.ui.container.Tab
        KtqudonEditField              matlab.ui.control.NumericEditField
        KtqudonEditFieldLabel         matlab.ui.control.Label
        KtquphngtrnhhiquyEditField    matlab.ui.control.NumericEditField
        KtquphngtrnhhiquyLabel        matlab.ui.control.Label
        NhpgitrcndonEditField         matlab.ui.control.EditField
        NhpgitrcndonEditFieldLabel    matlab.ui.control.Label
        PhngphpDropDown_3             matlab.ui.control.DropDown
        PhngphpDropDown_3Label        matlab.ui.control.Label
        NhpdliuyEditField_2           matlab.ui.control.EditField
        NhpdliuyEditField_2Label      matlab.ui.control.Label
        NhpdliuxEditField_2           matlab.ui.control.EditField
        NhpdliuxEditField_2Label      matlab.ui.control.Label
        ResetButton_3                 matlab.ui.control.Button
        UIAxes_3                      matlab.ui.control.UIAxes
        ohmTab                        matlab.ui.container.Tab
        KtquEditField                 matlab.ui.control.NumericEditField
        KtquEditFieldLabel            matlab.ui.control.Label
        PhngphpohmDropDown            matlab.ui.control.DropDown
        PhngphpohmDropDownLabel       matlab.ui.control.Label
        GitrsaisDropDown              matlab.ui.control.DropDown
        GitrsaisDropDownLabel         matlab.ui.control.Label
        NhpbchEditField               matlab.ui.control.EditField
        NhpbchEditFieldLabel          matlab.ui.control.Label
        NhphmsEditField               matlab.ui.control.EditField
        NhphmsEditFieldLabel          matlab.ui.control.Label
        HocLabel                      matlab.ui.control.Label
        NhpdliuyEditField_3           matlab.ui.control.EditField
        NhpdliuyEditField_3Label      matlab.ui.control.Label
        NhpdliuxEditField_3           matlab.ui.control.EditField
        NhpdliuxEditField_3Label      matlab.ui.control.Label
        TchphnTab                     matlab.ui.container.Tab
        NhpNEditField                 matlab.ui.control.EditField
        NhpNEditFieldLabel            matlab.ui.control.Label
        NhpcntnhtchphnEditField       matlab.ui.control.EditField
        NhpcntnhtchphnEditFieldLabel  matlab.ui.control.Label
        KtquEditField_2               matlab.ui.control.NumericEditField
        KtquEditField_2Label          matlab.ui.control.Label
        PhngphpTchphnDropDown         matlab.ui.control.DropDown
        PhngphpTchphnDropDownLabel    matlab.ui.control.Label
        NhphmsEditField_2             matlab.ui.control.EditField
        NhphmsEditField_2Label        matlab.ui.control.Label
        NhpdliuyEditField_4           matlab.ui.control.EditField
        NhpdliuyEditField_4Label      matlab.ui.control.Label
        NhpdliuxEditField_4           matlab.ui.control.EditField
        NhpdliuxEditField_4Label      matlab.ui.control.Label
        HocLabel_2                    matlab.ui.control.Label
        GiithiunhmTab                 matlab.ui.container.Tab
        NhimvTextArea                 matlab.ui.control.TextArea
        NhimvTextAreaLabel            matlab.ui.control.Label
        GiithiuthnhvinTextArea        matlab.ui.control.TextArea
        GiithiuthnhvinTextAreaLabel   matlab.ui.control.Label
        GiithiunhmTextArea            matlab.ui.control.TextArea
        GiithiunhmTextAreaLabel       matlab.ui.control.Label
    end

    
    methods (Access = public)
        
        function results = func(app)
            
        end
                % Nội suy


        function Nhap_sai(app)
            uialert(app.UIFigure, 'Nhập Sai', 'Thông báo');
            pause(0.1);
        end

        function fx = NS_lagrange(app, xi, yi)
            % xi, yi la dau vao x, y cco cung kich thuoc
            n = length(xi);
            syms x;
            % Tính L(i)
            L = sym(ones(1,n));
            for i = 1:n
                for j = 1:n
                    if (i ~= j)
                        L(i) = L(i)*(x-xi(j))/(xi(i)-xi(j));
                    end
                end
            end
            % Tính phương trình nội suy
            L  = L .* yi;
            c  = sum(L);
            fx = char(simplify(c));
        end
        
        function s = NS_newton(app, x_array, y_array)
            % xi, yi la dau vao x, y cco cung kich thuoc
            n = length(x_array);
            b = zeros(n,n);
            b(:,1) = y_array(:);
            for j = 2:n
                for i = 1:n-j+1
                    b(i,j) = (b(i+1,j-1)-b(i,j-1))/(x_array(i+j-1)-x_array(i));
                end
            end
            syms x;
            syms yout(x);
            yout = b(1,1); % b(0)
            xt = 1;
            % Tính phương trình nội suy
            for j = 1:n-1
                xt = xt*(x-x_array(j));
                yout = yout + (b(1,j+1)*xt);
            end
            s = char(collect(yout, x));
        end

               
        function result = Du_doan(app, s)
            x_0 = str2num(app.NoiSuy_Val.Value);
            syms x;
            y = str2func(['@(x)' char(s)]);
            result = num2str(y(x_0),3);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function NoiSuy_FigButtonDown(app, event)
            
        end

        % Button pushed function: ResetButton_2
        function NoiSuy_ValValueChanged(app, event)
            x = str2num(app.NoiSuy_x.Value);
            y = str2num(app.NoiSuy_y.Value);

            % Kiểm tra xem x, y có cùng kích thước
            if numel(x) ~= numel(y)
                uialert(app.UIFigure,"x, y phải cùng kích thước","Thông báo","Icon",'error');
                return
            end

            try
                switch app.NoiSuy_PP.Value
                    case 'Lagrange'
                        s = NS_lagrange(app, x, y);
                    case 'Newton'
                        s = NS_newton(app, x, y);
                end
            catch e
                Nhap_sai(app);
                return
            end
            app.KQ_NoiSuy.Value = Du_doan(app, s);
            app.KQ_DaThucNS.Value = s;
            
            syms u(x);
            u(x) = str2sym(s);
            hold (app.NoiSuy_Fig, 'on');
            F = fplot(app.NoiSuy_Fig, u);
            set(F, 'DisplayName', ['y = ', s]);
            legend(app.NoiSuy_Fig, 'show');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghimTab = uitab(app.TabGroup);
            app.NghimTab.Title = 'Nghiệm';
            app.UIAxes = uiaxes(app.NghimTab);
            title(app.UIAxes, 'Đồ thị Hàm số')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PhngtrnhEditFieldLabel = uilabel(app.NghimTab);
            app.PhngtrnhEditFieldLabel.Text = 'Phương trình:';
            app.PhngtrnhEditField = uieditfield(app.NghimTab, 'text');
            app.KhongphnlyEditFieldLabel = uilabel(app.NghimTab);
            app.KhongphnlyEditFieldLabel.Text = 'Khoảng phân ly:';
            app.KhongphnlyEditField = uieditfield(app.NghimTab, 'numeric');
            app.nEditFieldLabel = uilabel(app.NghimTab);
            app.nEditFieldLabel.Text = 'đến';
            app.nEditField = uieditfield(app.NghimTab, 'numeric');
            app.SaisEditFieldLabel = uilabel(app.NghimTab);
            app.SaisEditFieldLabel.Text = 'Sai số:';
            app.SaisEditField = uieditfield(app.NghimTab, 'text');
            app.SaisEditField.Value = '0';
            app.PhngphpDropDownLabel = uilabel(app.NghimTab);
            app.PhngphpDropDownLabel.Text = 'Phương pháp:';
            app.PhngphpDropDown = uidropdown(app.NghimTab);
            app.PhngphpDropDown.Items = {'Chia đôi', 'Lặp', 'Newton'};
            app.PhngphpDropDown.Value = 'Chia đôi';
            app.SlnlpLabel = uilabel(app.NghimTab);
            app.SlnlpLabel.Text = 'Số lần lặp:';
            app.SlnlpEditField = uieditfield(app.NghimTab, 'text');
            app.SlnlpEditField.Value = '0';
            app.KtqunghimEditFieldLabel = uilabel(app.NghimTab);
            app.KtqunghimEditFieldLabel.Text = 'Kết quả nghiệm:';
            app.KtqunghimEditField = uieditfield(app.NghimTab, 'numeric');
            app.TnhtonButton = uibutton(app.NghimTab, 'push');
            app.TnhtonButton.Text = 'Tính toán';
            app.ResetButton = uibutton(app.NghimTab, 'push');
            app.ResetButton.Text = 'Reset';
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.NoiSuy_Fig = uiaxes(app.NisuyTab);
            title(app.NoiSuy_Fig, 'Đồ thị số nội suy và dữ liệu thực ')
            xlabel(app.NoiSuy_Fig, 'X')
            ylabel(app.NoiSuy_Fig, 'Y')
            zlabel(app.NoiSuy_Fig, 'Z')
            app.NhpdliuxEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x:';
            app.NoiSuy_x = uieditfield(app.NisuyTab, 'text');
            app.NoiSuy_x.Value = '1, 2, 3, 4, 5';
            app.NhpdliuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y:';
            app.NoiSuy_y = uieditfield(app.NisuyTab, 'text');
            app.NoiSuy_y.Value = '1, 4, 9, 16, 25';
            app.PhngphpDropDown_2Label = uilabel(app.NisuyTab);
            app.PhngphpDropDown_2Label.Text = 'Phương pháp:';
            app.NoiSuy_PP = uidropdown(app.NisuyTab);
            app.NoiSuy_PP.Items = {'Newton', 'Lagrange'};
            app.NoiSuy_PP.Value = 'Newton';
            app.NhpgitrcnnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpgitrcnnisuyEditFieldLabel.Text = 'Nhập giá trị cần nội suy';
            app.NoiSuy_Val = uieditfield(app.NisuyTab, 'text');
            app.NoiSuy_Val.Value = '3.5';
            app.ResetButton_2 = uibutton(app.NisuyTab, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @NoiSuy_ValValueChanged, true);
            app.ResetButton_2.Text = 'Reset';
            app.KtqunisuyEditFieldLabel_2 = uilabel(app.NisuyTab);
            app.KtqunisuyEditFieldLabel_2.Text = 'Kết quả nội suy:';
            app.KQ_NoiSuy = uieditfield(app.NisuyTab, 'text');
            app.KtquathcnisuyTextAreaLabel = uilabel(app.NisuyTab);
            app.KtquathcnisuyTextAreaLabel.Text = 'Kết quả đa thức nội suy:';
            app.KQ_DaThucNS = uitextarea(app.NisuyTab);
            app.HiquyTab = uitab(app.TabGroup);
            app.HiquyTab.Title = 'Hồi quy';
            app.UIAxes_3 = uiaxes(app.HiquyTab);
            title(app.UIAxes_3, 'Đồ thị số hồi quy và dữ liệu thực ')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.ResetButton_3 = uibutton(app.HiquyTab, 'push');
            app.ResetButton_3.Text = 'Reset';
            app.NhpdliuxEditField_2Label = uilabel(app.HiquyTab);
            app.NhpdliuxEditField_2Label.Text = 'Nhập dữ liệu x:';
            app.NhpdliuxEditField_2 = uieditfield(app.HiquyTab, 'text');
            app.NhpdliuyEditField_2Label = uilabel(app.HiquyTab);
            app.NhpdliuyEditField_2Label.Text = 'Nhập dữ liệu y:';
            app.NhpdliuyEditField_2 = uieditfield(app.HiquyTab, 'text');
            app.PhngphpDropDown_3Label = uilabel(app.HiquyTab);
            app.PhngphpDropDown_3Label.Text = 'Phương pháp:';
            app.PhngphpDropDown_3 = uidropdown(app.HiquyTab);
            app.PhngphpDropDown_3.Items = {'Tuyến tính', 'Hàm mũ', 'Logarit'};
            app.PhngphpDropDown_3.Value = 'Logarit';
            app.NhpgitrcndonEditFieldLabel = uilabel(app.HiquyTab);
            app.NhpgitrcndonEditFieldLabel.Text = 'Nhập giá trị cần dự đoán:';
            app.NhpgitrcndonEditField = uieditfield(app.HiquyTab, 'text');
            app.KtquphngtrnhhiquyLabel = uilabel(app.HiquyTab);
            app.KtquphngtrnhhiquyLabel.Text = 'Kết quả phương trình hồi quy:';
            app.KtquphngtrnhhiquyEditField = uieditfield(app.HiquyTab, 'numeric');
            app.KtqudonEditFieldLabel = uilabel(app.HiquyTab);
            app.KtqudonEditFieldLabel.Text = 'Kết quả dự đoán:';
            app.KtqudonEditField = uieditfield(app.HiquyTab, 'numeric');
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.NhpdliuxEditField_3Label = uilabel(app.ohmTab);
            app.NhpdliuxEditField_3Label.Text = 'Nhập dữ liệu x:';
            app.NhpdliuxEditField_3 = uieditfield(app.ohmTab, 'text');
            app.NhpdliuyEditField_3Label = uilabel(app.ohmTab);
            app.NhpdliuyEditField_3Label.Text = 'Nhập dữ liệu y:';
            app.NhpdliuyEditField_3 = uieditfield(app.ohmTab, 'text');
            app.HocLabel = uilabel(app.ohmTab);
            app.HocLabel.Text = 'Hoặc';
            app.NhphmsEditFieldLabel = uilabel(app.ohmTab);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số:';
            app.NhphmsEditField = uieditfield(app.ohmTab, 'text');
            app.NhpbchEditFieldLabel = uilabel(app.ohmTab);
            app.NhpbchEditFieldLabel.Text = 'Nhập bước h:';
            app.NhpbchEditField = uieditfield(app.ohmTab, 'text');
            app.GitrsaisDropDownLabel = uilabel(app.ohmTab);
            app.GitrsaisDropDownLabel.Text = 'Giá trị sai số:';
            app.GitrsaisDropDown = uidropdown(app.ohmTab);
            app.GitrsaisDropDown.Items = {'O(h)', 'O(h^2)'};
            app.GitrsaisDropDown.Value = 'O(h)';
            app.PhngphpohmDropDownLabel = uilabel(app.ohmTab);
            app.PhngphpohmDropDownLabel.Text = 'Phương pháp đạo hàm:';
            app.PhngphpohmDropDown = uidropdown(app.ohmTab);
            app.PhngphpohmDropDown.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Trung tâm'};
            app.PhngphpohmDropDown.Value = 'Xấp xỉ tiến';
            app.KtquEditFieldLabel = uilabel(app.ohmTab);
            app.KtquEditFieldLabel.Text = 'Kết quả:';
            app.KtquEditField = uieditfield(app.ohmTab, 'numeric');
            app.TchphnTab = uitab(app.TabGroup);
            app.TchphnTab.Title = 'Tích phân';
            app.HocLabel_2 = uilabel(app.TchphnTab);
            app.HocLabel_2.Text = 'Hoặc';
            app.NhpdliuxEditField_4Label = uilabel(app.TchphnTab);
            app.NhpdliuxEditField_4Label.Text = 'Nhập dữ liệu x:';
            app.NhpdliuxEditField_4 = uieditfield(app.TchphnTab, 'text');
            app.NhpdliuyEditField_4Label = uilabel(app.TchphnTab);
            app.NhpdliuyEditField_4Label.Text = 'Nhập dữ liệu y:';
            app.NhpdliuyEditField_4 = uieditfield(app.TchphnTab, 'text');
            app.NhphmsEditField_2Label = uilabel(app.TchphnTab);
            app.NhphmsEditField_2Label.Text = 'Nhập hàm số:';
            app.NhphmsEditField_2 = uieditfield(app.TchphnTab, 'text');
            app.PhngphpTchphnDropDownLabel = uilabel(app.TchphnTab);
            app.PhngphpTchphnDropDownLabel.Text = 'Phương pháp Tích phân:';
            app.PhngphpTchphnDropDown = uidropdown(app.TchphnTab);
            app.PhngphpTchphnDropDown.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.PhngphpTchphnDropDown.Value = 'Hình thang';
            app.KtquEditField_2Label = uilabel(app.TchphnTab);
            app.KtquEditField_2Label.Text = 'Kết quả:';
            app.KtquEditField_2 = uieditfield(app.TchphnTab, 'numeric');
            app.NhpcntnhtchphnEditFieldLabel = uilabel(app.TchphnTab);
            app.NhpcntnhtchphnEditFieldLabel.Text = 'Nhập cận để tính tích phân:';
            app.NhpcntnhtchphnEditField = uieditfield(app.TchphnTab, 'text');
            app.NhpNEditFieldLabel = uilabel(app.TchphnTab);
            app.NhpNEditFieldLabel.Text = 'Nhập N:';
            app.NhpNEditField = uieditfield(app.TchphnTab, 'text');
            app.GiithiunhmTab = uitab(app.TabGroup);
            app.GiithiunhmTab.Title = 'Giới thiệu nhóm';
            app.GiithiunhmTextAreaLabel = uilabel(app.GiithiunhmTab);
            app.GiithiunhmTextAreaLabel.Text = 'Giới thiệu nhóm:';
            app.GiithiunhmTextArea = uitextarea(app.GiithiunhmTab);
            app.GiithiuthnhvinTextAreaLabel = uilabel(app.GiithiunhmTab);
            app.GiithiuthnhvinTextAreaLabel.Text = 'Giới thiệu thành viên:';
            app.GiithiuthnhvinTextArea = uitextarea(app.GiithiunhmTab);
            app.NhimvTextAreaLabel = uilabel(app.GiithiunhmTab);
            app.NhimvTextAreaLabel.Text = 'Nhiệm vụ:';
            app.NhimvTextArea = uitextarea(app.GiithiunhmTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BaiTapNhom

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end