classdef BaiTapNhom3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        NghimTab                       matlab.ui.container.Tab
        PhngtrnhLabel                  matlab.ui.control.Label
        fpEditField                    matlab.ui.control.EditField
        fpEditFieldLabel               matlab.ui.control.Label
        SlnlpEditField_2               matlab.ui.control.NumericEditField
        SlnlpEditField_2Label          matlab.ui.control.Label
        SaisEditField_2                matlab.ui.control.NumericEditField
        SaisEditField_2Label           matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        TnhtonButton                   matlab.ui.control.Button
        KtqunghimEditField             matlab.ui.control.NumericEditField
        KtqunghimEditFieldLabel        matlab.ui.control.Label
        PhngphpDropDown                matlab.ui.control.DropDown
        PhngphpDropDownLabel           matlab.ui.control.Label
        nEditField                     matlab.ui.control.NumericEditField
        nEditFieldLabel                matlab.ui.control.Label
        KhongphnlyEditField            matlab.ui.control.NumericEditField
        KhongphnlyEditFieldLabel       matlab.ui.control.Label
        PhuongTrinh                    matlab.ui.control.EditField
        Image                          matlab.ui.control.Image
        UIAxes                         matlab.ui.control.UIAxes
        NisuyTab                       matlab.ui.container.Tab
        KQ_DaThucNS                    matlab.ui.control.EditField
        KtquathcnisuyEditFieldLabel_2  matlab.ui.control.Label
        KQ_NoiSuy                      matlab.ui.control.EditField
        KtqunisuyEditFieldLabel_2      matlab.ui.control.Label
        TnhtonButton_3                 matlab.ui.control.Button
        ResetButton_2                  matlab.ui.control.Button
        NoiSuy_Val                     matlab.ui.control.EditField
        NhpgitrcnnisuyEditFieldLabel   matlab.ui.control.Label
        NoiSuy_PP                      matlab.ui.control.DropDown
        NoiSuy_x                       matlab.ui.control.EditField
        NoiSuy_y                       matlab.ui.control.EditField
        PhngphpDropDown_2Label         matlab.ui.control.Label
        NhpdliuyEditFieldLabel         matlab.ui.control.Label
        NhpdliuxEditFieldLabel         matlab.ui.control.Label
        Image5                         matlab.ui.control.Image
        NoiSuy_Fig                     matlab.ui.control.UIAxes
        HiquyTab                       matlab.ui.container.Tab
        NhpgitrcndonEditField          matlab.ui.control.NumericEditField
        NhpgitrcndonEditField_2Label   matlab.ui.control.Label
        KtquphngtrnhhiquyEditField     matlab.ui.control.EditField
        KtquphngtrnhhiquyEditField_2Label_2  matlab.ui.control.Label
        KtqudonEditField               matlab.ui.control.NumericEditField
        KtqudonEditField_2Label        matlab.ui.control.Label
        TnhtonButton_2                 matlab.ui.control.Button
        PhngphpDropDown_3              matlab.ui.control.DropDown
        PhngphpDropDown_3Label         matlab.ui.control.Label
        NhpdliuyEditField_2            matlab.ui.control.EditField
        NhpdliuyEditField_2Label       matlab.ui.control.Label
        NhpdliuxEditField_2            matlab.ui.control.EditField
        NhpdliuxEditField_2Label       matlab.ui.control.Label
        ResetButton_3                  matlab.ui.control.Button
        Image3                         matlab.ui.control.Image
        UIAxes_3                       matlab.ui.control.UIAxes
        ohmTab                         matlab.ui.container.Tab
        h_daoham                       matlab.ui.control.NumericEditField
        NhpbcnhyhLabel                 matlab.ui.control.Label
        Y_Du_doan_Dao_ham              matlab.ui.control.EditField
        KtquEditField_3Label           matlab.ui.control.Label
        X_Du_doan_Dao_ham              matlab.ui.control.EditField
        NhapgiatrixcantinhLabel        matlab.ui.control.Label
        Tinh_Dao_ham                   matlab.ui.control.Button
        Cachnhap2                      matlab.ui.control.CheckBox
        Cachnhap1                      matlab.ui.control.CheckBox
        PP_Dao_ham                     matlab.ui.control.DropDown
        PhngphpohmDropDownLabel        matlab.ui.control.Label
        Saiso_daoham                   matlab.ui.control.DropDown
        GitrsaisDropDownLabel          matlab.ui.control.Label
        Ham_so_Dao_ham                 matlab.ui.control.EditField
        NhphmsEditFieldLabel           matlab.ui.control.Label
        HocLabel                       matlab.ui.control.Label
        Data_x_Dao_ham                 matlab.ui.control.EditField
        NhpdliuxEditField_3Label       matlab.ui.control.Label
        Data_y_Dao_ham                 matlab.ui.control.EditField
        NhpdliuyEditField_3Label       matlab.ui.control.Label
        Image4                         matlab.ui.control.Image
        TchphnTab                      matlab.ui.container.Tab
        Image8_2                       matlab.ui.control.Image
        Image8                         matlab.ui.control.Image
        Image7                         matlab.ui.control.Image
        InputMeth                      matlab.ui.control.Switch
        Label                          matlab.ui.control.Label
        TP_N                           matlab.ui.control.NumericEditField
        NhpNEditFieldLabel_2           matlab.ui.control.Label
        ResetButton_4                  matlab.ui.control.Button
        TP_Calc                        matlab.ui.control.Button
        TP_Can                         matlab.ui.control.EditField
        NhpcntnhtchphnEditFieldLabel   matlab.ui.control.Label
        TP_KQ                          matlab.ui.control.NumericEditField
        KtquEditField_2Label           matlab.ui.control.Label
        TP_PhuongPhap                  matlab.ui.control.DropDown
        PhngphpTchphnDropDownLabel     matlab.ui.control.Label
        TP_HamSo                       matlab.ui.control.EditField
        NhphmsEditField_2Label         matlab.ui.control.Label
        TP_Y_value                     matlab.ui.control.EditField
        NhpdliuyEditField_4Label       matlab.ui.control.Label
        TP_X_value                     matlab.ui.control.EditField
        NhpdliuxEditField_4Label       matlab.ui.control.Label
        HocLabel_2                     matlab.ui.control.Label
        Image6                         matlab.ui.control.Image
        GiithiunhmTab                  matlab.ui.container.Tab
        NhimvTextArea                  matlab.ui.control.TextArea
        NhimvTextAreaLabel             matlab.ui.control.Label
        GiithiunhmTextArea             matlab.ui.control.TextArea
        GiithiunhmTextAreaLabel        matlab.ui.control.Label
        GiithiuthnhvinTextArea         matlab.ui.control.TextArea
        GiithiuthnhvinLabel            matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        function [A1, A0] = hoiQuyTinhToan(app, X, Y, n)
            sumX=0; sumY=0; sumXY=0; sumX2=0;
            for i = 1:n
                sumX = sumX + X(i);
                sumY = sumY + Y(i);
                sumXY = sumXY + X(i)*Y(i);
                sumX2 = sumX2 + X(i)*X(i);
            end
            Xm = sumX / n;
            Ym = sumY / n;
            A1 = (n*sumXY - sumX*sumY) / (n*sumX2 - sumX*sumX);
            A0 = Ym - A1*Xm;
        end
        
        function a = DH_lui_xy(x, y, x_dh, h, Oh, app)
            % Oh = 2 là dạng O(h^2)
            % Oh = 1 là dạng O(h)
            % x_dh khong thuoc day x thi khong tinh duoc dao ham
            a='';
            if isempty(find(x==x_dh))
                uialert(app.UIFigure,"Không tính được đạo hàm", "Thông Báo", "Icon",'error');
                return
            end
            if Oh == 1
                if (find(x==x_dh) == 1)
                    uialert(app.UIFigure,"Không tính được đạo hàm", "Thông Báo", "Icon",'error');
                    return
                end
                i = find(x==x_dh);
                a = (y(i)-y(i-1))/h;
            else
                if (find(x==x_dh) < 3)
                    a = 'Khong tinh duoc dao ham';
                    return
                end
                i = find(x==x_dh);
                a = (3.*y(i) - 4.*y(i-1) + y(i-2))/(2*h); %O(h^2)
            end
        end

        function a = DH_tien_xy(x, y, x_dh, h, Oh, app)
            % x_dh khong thuoc day x thi khong tinh duoc dao ham
            i=1;
            a = '';
            while (x(i)~=x_dh)
                i=i+1;
                if (i>length(x))
                    uialert(app.UIFigure,"Không tính được đạo hàm", "Thông Báo",'OK');
                    return;
                end
            end
            if (length(x)-i)<2
                uialert(app.UIFigure,"Không tính được đạo hàm", "Thông Báo", 'OK');
                return
            end
            switch Oh
                case 1 %Oh
                    a=((y(i+1))-y((i)))/(h);
                case 2 %Oh^2
                    a= ((-y(i+2))+4*(y(i+1))-3*(y(i)))/(h*2);
            end
        end

        function a = DH_tt_xy(x, y, x_dh, h, Oh, app)
            % x, y ở dạng số
            % h bước nhảy
            % Oh loại sai số bắt cụt
            % Oh = 2 là dạng O(h^2)
            % Oh = 1 là dạng O(h)
            % x_dh khong thuoc day x thi khong tinh duoc dao ham
            i=1;
            a='';
            while (x(i)~=x_dh) %kiem tra x_dh thuoc mang x
                i=i+1;
                if (i>length(x))
                    uialert(app.UIFigure,"Không tính được đạo hàm", "Thông Báo", "Icon",'error');
                    return;
                end
            end
            if (((length(x)-i)<(Oh/2)) || (i < Oh))
                %kiem tra so luong phan tu 2 chieu
                uialert(app.UIFigure,"Không tính được đạo hàm", "Thông Báo", "Icon",'error');
                return;
            end
            a=((y(i+1))-y((i-1)))/(2*h);
        end

        function Nhap_sai(app)
            uialert(app.UIFigure, 'Nhập Sai', 'Thông báo');
            pause(0.1);
        end

        function tp = TP_Simpson13_xy(~, x, y)
            % x, y la mang gia tri
            % a, b la can
            % N chia nho N: chan
            N = length(x) - 1;
            h = x(2) - x(1);
            i = 1:2:N-1;
            j = 2:2:N-2;
            tp = (h/3) * (y(1) + y(N + 1) + 4*sum(y(1 + i)) + 2*sum(y(1 + j)));
        end

        function I = TP_Simpson38_xy(~, x, y)
            N = length(x) - 1;
            h = x(2) - x(1);    
            I = y(1) + y(N + 1);
            for i = 1:(N - 1)
                if rem(i, 3) == 0 
                    I = I + 2 * y(1 + i);
                else
                    I = I + 3 * y(1 + i);
                end
            end
            I = I * 3 * (h / 8);
        end


        function S = TP_Hinhthang_Ham(~, f, a, b, N)
            fx = str2func(['@(x)', f]);
            h = (b - a)/N;
            S = h/2*(fx(a)+fx(b));
            for i = 1:N-1
                S = S + h*sum(fx(a+i*h));
            end
        end

        function tp = TP_Simpson13_Ham(~, f, a, b, N)
            syms g(x)
            g(x) = str2sym(char(f));
            if rem(N,2) ~= 0
                N = N + 1;
            end
            h = (b - a)/N;
            i = 1:2:N-1;
            j = 2:2:N-2;
            tp = (h/3) * (g(a) + g(b) + 4*sum(g(a + i*h)) + 2*sum(g(a + j*h)));
            tp = str2num(char(tp));
        end

        function I = TP_Simpson38_Ham(~, f, a, b, N)
            f = append('@(x)',f);
            fx = str2func(char(f));
            h = (b-a)/N;
            I = fx(a) + fx(b);
            for i = 1:(N-1)
                k = a + i*h;
                if rem(i,3) == 0
                    I = I + 2 * fx(k);
                else
                    I = I + 3 * fx(k);
                end
            end
            I = I * 3 * (h/8);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TnhtonButton
        function TnhtonButtonPushed(app, event)
            fun = app.PhuongTrinh.Value;
            fp = app.fpEditField.Value;
            a = app.KhongphnlyEditField.Value;
            b = app.nEditField.Value;
            method_1 = app.PhngphpDropDown.Value;
            method_1 = string(method_1);
            es = app.SaisEditField_2.Value; %Sai số cho trước
            max = app.SlnlpEditField_2.Value;
            x = -10:0.0001:10;
            n = 0;
            switch method_1
                case {'Chia đôi'}
                    fx = str2func(['@(x)',fun]);
                        y = fx(x);
                    while n < max
                        n = n+1;
                        c = (a+b)/2;
                        fafc = fx(a)*fx(c);
                        if fafc < 0
                            b = c;
                        else
                            a = c;
                        end
                        ea = abs(b - a);
                        if (fafc == 0)
                            ea=0;
                        end
                        if ea < es
                            break
                        end
                    end
                plot(app.UIAxes,x,y);
                app.KtqunghimEditField.Value = c;
                case {'Lặp'} 
                    fx = str2func(['@(x)',fun]);
                    y = fx(x);
                    fp = str2func(['@(x)',fp]);
                    c = (a+b)/2;
                    if fx(a) * fx(c) < 0
                        x0 = b;
                    else
                        x0 = a;
                    end
                    while n < max
                        n = n+1;
                        x1 = fp(x0);
                        ea = abs(x1 - x0);
                        if ea < es
                            break;
                        end
                        x0 = x1;
                    end
                    plot(app.UIAxes,x,y);
                    app.KtqunghimEditField.Value = x1;
                case {'Newton'}
                    fx = str2sym(fun);
                    fp = diff(fx);
                    fpp = diff(fp);
                    fp = matlabFunction(fp);
                    fpp = matlabFunction(fpp);
                    fx = matlabFunction(fx);
                    n = 0;
                    if fx(a) * fpp(a) > 0
                        x0 = b;
                    else
                        x0 = a;
                    end
                    while n < max
                        n = n+1;
                        x1 = x0 - (fx(x0)/fp(x0));
                        ea = abs(x1 - x0);
                        if ea < es
                            break;
                        end
                        x0 = x1;
                    end
                    y = fx(x);
                    plot(app.UIAxes,x,y);
                    app.KtqunghimEditField.Value = x1;
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.KhongphnlyEditField.Value = 0;
            app.nEditField.Value = 0;
            app.PhuongTrinh.Value = '';
            app.fpEditField.Value = '';
            app.SaisEditField_2.Value = 0;
            app.SlnlpEditField_2.Value = 0;
            app.KtqunghimEditField.Value = 0;
            plot(app.UIAxes,0,0);
        end

        % Button pushed function: TnhtonButton_2
        function TnhtonButton_2Pushed(app, event)
            % Lấy dữ liệu từ client
            x_text = app.NhpdliuxEditField_2.Value;
            y_text = app.NhpdliuyEditField_2.Value;
            P_text = app.PhngphpDropDown_3.Value;
            x_find = app.NhpgitrcndonEditField.Value;
            
            % Chuyển đổi và khai báo
            x = str2num(x_text);
            y = str2num(y_text);
            n = length(x);
            if(length(x) ~= length(y))
                ResetButton_3Pushed(app, event);
                app.KtquphngtrnhhiquyEditField.Value = 'Lỗi chiều dài chuỗi x khác y';
                return;
            end
       
            % Tính toán
            if(isequal(P_text, 'P(x) = ax + b'))
                % Data for test
                % x = [6 7 11 15 17 21 23 29 29 37 39]
                % y = [29 21 29 14 21 15 7 7 13 0 3]
                X = x; Y = y;
                [A1, A0] = hoiQuyTinhToan(app, X, Y, n);
                a = A1; b = A0;
                
                app.KtquphngtrnhhiquyEditField.Value = [num2str(a) 'x + ' num2str(b)];
                app.KtqudonEditField.Value = a*x_find + b;
                cla(app.UIAxes_3);
                hold(app.UIAxes_3, 'on');
                plot(app.UIAxes_3, x, y, 'o');
                plot(app.UIAxes_3, x, a*x + b);
                legend(app.UIAxes_3, 'f(xi)', 'P(x)');
                hold(app.UIAxes_3, 'off');  
            elseif(isequal(P_text, 'P(x) = ax^b'))
                % Data for test
                % [1 2 3 4 5]
                % [0.5 1.7 3.4 5.7 8.4]
                X = log10(x); Y = log10(y);
                [A1, A0] = hoiQuyTinhToan(app, X, Y, n);
                a = 10.^A0; b = A1;
                
                app.KtquphngtrnhhiquyEditField.Value = [num2str(a) 'x^' num2str(b)];
                app.KtqudonEditField.Value = a * x_find.^b;
                cla(app.UIAxes_3);
                hold(app.UIAxes_3, 'on');
                plot(app.UIAxes_3, x, y, 'o');
                plot(app.UIAxes_3, x, a * x.^b);
                legend(app.UIAxes_3, 'f(xi)', 'P(x)');
                hold(app.UIAxes_3, 'off');
            elseif(isequal(P_text, 'P(x) = aln(x) + b'))
                % Data for test
                % [0.25 2.0 4.0 7.0 10.1]
                % [0 4.7 6.4 7.7 8.6]
                X = log(x); Y = y;
                [A1, A0] = hoiQuyTinhToan(app, X, Y, n);
                a = A1; b = A0;
                app.KtquphngtrnhhiquyEditField.Value = [num2str(a) 'ln(x) + ' num2str(b)];
                app.KtqudonEditField.Value = a*log(x_find) + b;
                cla(app.UIAxes_3);
                hold(app.UIAxes_3, 'on');
                plot(app.UIAxes_3, x, y, 'o');
                plot(app.UIAxes_3, x, a*log(x) + b);
                legend(app.UIAxes_3, 'f(xi)', 'P(x)');
                hold(app.UIAxes_3, 'off');
            elseif(isequal(P_text, 'P(x) = ae^(bx)'))
                % Data for test
                % [0.65 0.75 0.85 0.95 1.15]
                % [0.96 1.06 1.17 1.29 1.58]
                X = x; Y = log(y);
                [A1, A0] = hoiQuyTinhToan(app, X, Y, n);
                a = exp(A0); b = A1;
                app.KtquphngtrnhhiquyEditField.Value = [num2str(a) 'e^(' num2str(b) 'x)'];
                app.KtqudonEditField.Value = a*exp(b*x_find);
                cla(app.UIAxes_3);
                hold(app.UIAxes_3, 'on');
                plot(app.UIAxes_3, x, y, 'o');
                plot(app.UIAxes_3, x, a*exp(b*x));
                legend(app.UIAxes_3, 'f(xi)', 'P(x)');
                hold(app.UIAxes_3, 'off');
            end
        end

        % Button pushed function: ResetButton_3
        function ResetButton_3Pushed(app, event)
            app.NhpdliuxEditField_2.Value = '';
            app.NhpdliuyEditField_2.Value = '';
            app.PhngphpDropDown_3.Value = 'P(x) = ax + b';
            app.NhpgitrcndonEditField.Value = 0;
            app.KtquphngtrnhhiquyEditField.Value = '0';
            app.KtqudonEditField.Value = 0;
            cla(app.UIAxes_3);
        end

        % Value changed function: Cachnhap1
        function Cachnhap1ValueChanged(app, event)
            value = app.Cachnhap1.Value;
            app.Data_y_Dao_ham.Editable = value;
            app.Data_x_Dao_ham.Editable = value;
            app.Ham_so_Dao_ham.Editable = 'off';
        end

        % Value changed function: Cachnhap2
        function Cachnhap2ValueChanged(app, event)
            value = app.Cachnhap2.Value;
            app.Ham_so_Dao_ham.Editable = value;
            app.Data_y_Dao_ham.Editable = 'off';
            app.Data_x_Dao_ham.Editable = 'off';
        end

        % Button pushed function: Tinh_Dao_ham
        function Tinh_Dao_hamButtonPushed(app, event)
            x = str2num(app.Data_x_Dao_ham.Value);
            y = str2num(app.Data_y_Dao_ham.Value);
            s = app.Saiso_daoham.Value; % O(h) và O(h^2)
            x_0 = str2num(app.X_Du_doan_Dao_ham.Value);
            fx = app.Ham_so_Dao_ham.Value;
            h = app.h_daoham.Value;
            
            if strcmp(s, 'O(h)')
                s = 1; % O(h)
            else
                s = 2; % O(h^2)
            end
            
            try
                if app.Cachnhap1.Value
                    if numel(x) ~= numel(y)
                        uialert(app.UIFigure,"y và x nên cùng kích thước", "Thông Báo", "Icon",'error');
                        return
                    end
                    switch app.PP_Dao_ham.Value
                        case "Xấp xỉ lùi"
                            app.Y_Du_doan_Dao_ham.Value = num2str(DH_lui_xy(x,y,x_0,h,s,app));
                        case "Xấp xỉ tiến"
                            app.Y_Du_doan_Dao_ham.Value = num2str(DH_tien_xy(x,y,x_0,h,s,app));
                        case "Trung tâm"
                            app.Y_Du_doan_Dao_ham.Value = num2str(DH_tt_xy(x,y,x_0,h,2,app));
                    end
                end
                if app.Cachnhap2.Value
                    switch app.PP_Dao_ham.Value
                        case "Xấp xỉ lùi"
                            app.Y_Du_doan_Dao_ham.Value = num2str(DH_lui_ham(char(fx),x_0,h,s));
                        case "Xấp xỉ tiến"
                            app.Y_Du_doan_Dao_ham.Value = num2str(DH_tien_ham(char(fx),x_0,h,s));
                        case "Trung tâm"
                            app.Y_Du_doan_Dao_ham.Value = num2str(DH_tt_ham(char(fx),x_0,h,2));
                    end
                end
            catch
             
                return
            end
            pause(0.100);
            
            if ~isempty(app.Y_Du_doan_Dao_ham.Value)
                uialert(app.UIFigure,"Successful","Thông Báo","Icon","success");
            end

            function a = DH_lui_ham(f,x,h,Oh)
                f = str2func(['@(x)' f]);
                if Oh == 1 %Oh
                    a=(f(x)-f(x-h))/h;
                elseif Oh == 2  %Oh^2
                    a=(3*f(x)-4*f(x-h)+f(x-2*h))/(2*h);
                end
            end

            function a = DH_tien_ham(f,x,h,Oh)
                % Oh = 2 là dạng O(h^2)
                % Oh = 1 là dạng O(h)
                fx = str2func(['@(x)' f]);
                if Oh == 1
                    a =(fx(x+h)-fx(x))/h;
                elseif Oh == 2
                    a=(-fx(x+2*h)+4*fx(x+1*h)-3*fx(x))/(2*h);
                end
            end
    
            function a = DH_tt_ham(f,x,h,Oh)
                % f ở dạng string
                % Oh loại sai số bắt cụt
                % Oh là dạng O(h^2)
                fx = str2func(['@(x)' f]);
                a = (fx(x+h)-fx(x-h))/(2*h);
            end
        end

        % Button pushed function: TnhtonButton_3
        function TnhtonButton_3Pushed(app, event)
            x = str2num(app.NoiSuy_x.Value);
            y = str2num(app.NoiSuy_y.Value);

            % Kiểm tra xem x, y có cùng kích thước
            if length(x) ~= length(y)
                uialert(app.UIFigure,"2 Mảng không cùng kích thước." + newline + "Vui lòng nhập lại.","Thông báo","Icon",'error');
                return
            end

            cla(app.NoiSuy_Fig);
            legend(app.NoiSuy_Fig, 'show');
            hold (app.NoiSuy_Fig, 'on');
            plot(app.NoiSuy_Fig, x, y, 'o');
            try
                switch app.NoiSuy_PP.Value
                    case 'Lagrange'
                        s = NS_lagrange(app, x, y);
                    case 'Newton'
                        s = NS_newton(app, x, y);
                end
            catch
                Nhap_sai(app);
                return
            end

            app.KQ_NoiSuy.Value = Du_doan(app, s);
            app.KQ_DaThucNS.Value = s;
           
            syms f(x);
            f(x) = str2sym(s);
            %
            F = fplot(app.NoiSuy_Fig, f);
            set(F, 'DisplayName', ['y = ', s]);
            hold (app.NoiSuy_Fig, 'off');


       function results = func(app)
            
        end
                % Nội suy
        function Nhap_sai(app)
            uialert(app.UIFigure, 'Nhập Sai', 'Thông báo');
            pause(0.1);
        end

        function fx = NS_lagrange(app, xa, ya)
            % xa, ya la input có cùng kích thước
            n = length(xa);
            syms x;
            L = sym(ones(1,n));
            for i = 1:n
                for j = 1:n
                    if (i ~= j)
                        L(i) = L(i)*(x-xa(j))/(xa(i)-xa(j));
                    end
                end
            end
            % Tính pt nội suy
            L  = L .* ya;
            c  = sum(L);
            fx = char(simplify(c));
        end
        
        function s = NS_newton(app, xi, yi)
            % xi, yi la input có cùng kích thước
            n = length(xi);
            b = zeros(n,n);
            b(:,1) = yi(:);
            for j = 2:n
                for i = 1:n-j+1
                    b(i,j) = (b(i+1,j-1)-b(i,j-1))/(xi(i+j-1)-xi(i));
                end
            end
            syms x;
            syms y;
            y = b(1,1);
            xt = 1;
            % Tính pt nội suy
            for j = 1:n-1
                xt = xt*(x-xi(j));
                y = y + (b(1,j+1)*xt);
            end
            s = char(collect(y, x));
        end

               
        function result = Du_doan(app, s)
            x_0 = str2num(app.NoiSuy_Val.Value);
            syms x;
            y = str2func(['@(x)' char(s)]);
            result = num2str(y(x_0),3);
        end
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            app.NoiSuy_x.Value = '';
            app.NoiSuy_y.Value = '';
            app.NoiSuy_Val.Value = '';
            app.NoiSuy_PP.Value = 'Newton';
            app.KQ_DaThucNS.Value = '0';
            app.KQ_NoiSuy.Value = '0';
            cla(app.NoiSuy_Fig);
        end

        % Value changed function: InputMeth
        function InputMethValueChanged(app, event)
            app.TP_X_value.Editable = ~app.InputMeth.Value;
            app.TP_Y_value.Editable = ~app.InputMeth.Value;
            app.TP_HamSo.Editable = app.InputMeth.Value;
            if (app.InputMeth.Value == 0)
                app.TP_PhuongPhap.Items = {'Simpson 1/3', 'Simpson 3/8'};
            else
                app.TP_PhuongPhap.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            end
        end

        % Button pushed function: TP_Calc
        function TP_CalcButtonPushed(app, event)
            try
                if app.InputMeth.Value == 0
                    x = str2num(app.TP_X_value.Value);
                    y = str2num(app.TP_Y_value.Value);
                    if numel(x) ~= numel(y)
                        uialert(app.UIFigure,"x, y phải cùng kích thước","Thông báo","Icon",'error');
                        return
                    end
                    switch app.TP_PhuongPhap.Value
                        case "Simpson 1/3"
                            app.TP_KQ.Value = TP_Simpson13_xy(app, x, y);
                        case "Simpson 3/8"
                            app.TP_KQ.Value = TP_Simpson38_xy(app, x, y);
                    end
                end
                
                if app.InputMeth.Value == 1
                    can = str2num(app.TP_Can.Value);
                    a = can(1);
                    b = can(end);
                    f = app.TP_HamSo.Value;
                    N = app.TP_N.Value;
                    switch app.TP_PhuongPhap.Value
                        case "Simpson 3/8"
                            app.TP_KQ.Value = TP_Simpson38_Ham(app, f, a, b, N);
                        case "Simpson 1/3"
                            app.TP_KQ.Value = TP_Simpson13_Ham(app, f, a, b, N);
                        case "Hình thang"
                            app.TP_KQ.Value = TP_Hinhthang_Ham(app, f, a, b, N);
                    end
                end
            catch
                app.TP_KQ.Value = 00000;
                Nhap_sai(app);
                return
            end
            uialert(app.UIFigure,"Đã tính xong","Thông Báo","Icon",'success');
        end

        % Button pushed function: ResetButton_4
        function ResetButton_4Pushed(app, event)
            app.TP_X_value.Value = '';
            app.TP_Y_value.Value = '';
            app.TP_HamSo.Value = '';
            app.TP_PhuongPhap.Value = 'Simpson 1/3';
            app.TP_Can.Value = '0';
            app.TP_N.Value = 0;
            app.TP_KQ.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghimTab = uitab(app.TabGroup);
            app.NghimTab.Title = 'Nghiệm';
            app.UIAxes = uiaxes(app.NghimTab);
            title(app.UIAxes, 'Đồ thị Hàm số')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.NghimTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Duykha5.png');
            app.PhuongTrinh = uieditfield(app.NghimTab, 'text');
            app.KhongphnlyEditFieldLabel = uilabel(app.NghimTab);
            app.KhongphnlyEditFieldLabel.Text = 'Khoảng phân ly:';
            app.KhongphnlyEditField = uieditfield(app.NghimTab, 'numeric');
            app.nEditFieldLabel = uilabel(app.NghimTab);
            app.nEditFieldLabel.Text = 'đến';
            app.nEditField = uieditfield(app.NghimTab, 'numeric');
            app.PhngphpDropDownLabel = uilabel(app.NghimTab);
            app.PhngphpDropDownLabel.Text = 'Phương pháp:';
            app.PhngphpDropDown = uidropdown(app.NghimTab);
            app.PhngphpDropDown.Items = {'Chia đôi', 'Lặp', 'Newton'};
            app.PhngphpDropDown.Value = 'Chia đôi';
            app.KtqunghimEditFieldLabel = uilabel(app.NghimTab);
            app.KtqunghimEditFieldLabel.Text = 'Kết quả nghiệm:';
            app.KtqunghimEditField = uieditfield(app.NghimTab, 'numeric');
            app.TnhtonButton = uibutton(app.NghimTab, 'push');
            app.TnhtonButton.ButtonPushedFcn = createCallbackFcn(app, @TnhtonButtonPushed, true);
            app.TnhtonButton.Text = 'Tính toán';
            app.ResetButton = uibutton(app.NghimTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SaisEditField_2Label = uilabel(app.NghimTab);
            app.SaisEditField_2Label.Text = 'Sai số:';
            app.SaisEditField_2 = uieditfield(app.NghimTab, 'numeric');
            app.SlnlpEditField_2Label = uilabel(app.NghimTab);
            app.SlnlpEditField_2Label.Text = 'Số lần lặp:';
            app.SlnlpEditField_2 = uieditfield(app.NghimTab, 'numeric');
            app.fpEditFieldLabel = uilabel(app.NghimTab);
            app.fpEditFieldLabel.Text = 'fp:';
            app.fpEditField = uieditfield(app.NghimTab, 'text');
            app.PhngtrnhLabel = uilabel(app.NghimTab);
            app.PhngtrnhLabel.Text = 'Phương trình:';
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.NoiSuy_Fig = uiaxes(app.NisuyTab);
            title(app.NoiSuy_Fig, 'Đồ thị số nội suy và dữ liệu thực ')
            xlabel(app.NoiSuy_Fig, 'X')
            ylabel(app.NoiSuy_Fig, 'Y')
            zlabel(app.NoiSuy_Fig, 'Z')
            app.Image5 = uiimage(app.NisuyTab);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'TraChi4.png');
            app.NhpdliuxEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x:';
            app.NhpdliuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y:';
            app.PhngphpDropDown_2Label = uilabel(app.NisuyTab);
            app.PhngphpDropDown_2Label.Text = 'Phương pháp:';
            app.NoiSuy_y = uieditfield(app.NisuyTab, 'text');
            app.NoiSuy_x = uieditfield(app.NisuyTab, 'text');
            app.NoiSuy_PP = uidropdown(app.NisuyTab);
            app.NoiSuy_PP.Items = {'Newton', 'Lagrange'};
            app.NoiSuy_PP.Value = 'Newton';
            app.NhpgitrcnnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpgitrcnnisuyEditFieldLabel.Text = 'Nhập giá trị cần nội suy';
            app.NoiSuy_Val = uieditfield(app.NisuyTab, 'text');
            app.NoiSuy_Val.Value = '0';
            app.ResetButton_2 = uibutton(app.NisuyTab, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.TnhtonButton_3 = uibutton(app.NisuyTab, 'push');
            app.TnhtonButton_3.ButtonPushedFcn = createCallbackFcn(app, @TnhtonButton_3Pushed, true);
            app.TnhtonButton_3.Text = 'Tính toán';
            app.KtqunisuyEditFieldLabel_2 = uilabel(app.NisuyTab);
            app.KtqunisuyEditFieldLabel_2.Text = 'Kết quả nội suy:';
            app.KQ_NoiSuy = uieditfield(app.NisuyTab, 'text');
            app.KQ_NoiSuy.Value = '0';
            app.KtquathcnisuyEditFieldLabel_2 = uilabel(app.NisuyTab);
            app.KtquathcnisuyEditFieldLabel_2.Text = 'Kết quả đa thức nội suy:';
            app.KQ_DaThucNS = uieditfield(app.NisuyTab, 'text');
            app.KQ_DaThucNS.Value = '0';
            app.HiquyTab = uitab(app.TabGroup);
            app.HiquyTab.Title = 'Hồi quy';
            app.UIAxes_3 = uiaxes(app.HiquyTab);
            title(app.UIAxes_3, 'Đồ thị số hồi quy và dữ liệu thực ')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.Image3 = uiimage(app.HiquyTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'HuyHoang1.png');
            app.ResetButton_3 = uibutton(app.HiquyTab, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_3Pushed, true);
            app.ResetButton_3.Text = 'Reset';
            app.NhpdliuxEditField_2Label = uilabel(app.HiquyTab);
            app.NhpdliuxEditField_2Label.Text = 'Nhập dữ liệu x:';
            app.NhpdliuxEditField_2 = uieditfield(app.HiquyTab, 'text');
            app.NhpdliuyEditField_2Label = uilabel(app.HiquyTab);
            app.NhpdliuyEditField_2Label.Text = 'Nhập dữ liệu y:';
            app.NhpdliuyEditField_2 = uieditfield(app.HiquyTab, 'text');
            app.PhngphpDropDown_3Label = uilabel(app.HiquyTab);
            app.PhngphpDropDown_3Label.Text = 'Phương pháp:';
            app.PhngphpDropDown_3 = uidropdown(app.HiquyTab);
            app.PhngphpDropDown_3.Items = {'P(x) = ax + b', 'P(x) = ax^b', 'P(x) = aln(x) + b', 'P(x) = ae^(bx)'};
            app.PhngphpDropDown_3.Value = 'P(x) = ax + b';
            app.TnhtonButton_2 = uibutton(app.HiquyTab, 'push');
            app.TnhtonButton_2.ButtonPushedFcn = createCallbackFcn(app, @TnhtonButton_2Pushed, true);
            app.TnhtonButton_2.Text = 'Tính toán';
            app.KtqudonEditField_2Label = uilabel(app.HiquyTab);
            app.KtqudonEditField_2Label.Text = 'Kết quả dự đoán:';
            app.KtqudonEditField = uieditfield(app.HiquyTab, 'numeric');
            app.KtquphngtrnhhiquyEditField_2Label_2 = uilabel(app.HiquyTab);
            app.KtquphngtrnhhiquyEditField_2Label_2.Text = 'Kết quả phương trình hồi quy:';
            app.KtquphngtrnhhiquyEditField = uieditfield(app.HiquyTab, 'text');
            app.KtquphngtrnhhiquyEditField.Value = '0';
            app.NhpgitrcndonEditField_2Label = uilabel(app.HiquyTab);
            app.NhpgitrcndonEditField_2Label.Text = 'Nhập giá trị cần dự đoán:';
            app.NhpgitrcndonEditField = uieditfield(app.HiquyTab, 'numeric');
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.Image4 = uiimage(app.ohmTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'quangdang2-01.png');
            app.NhpdliuyEditField_3Label = uilabel(app.ohmTab);
            app.NhpdliuyEditField_3Label.Text = 'Nhập dữ liệu y:';
            app.Data_y_Dao_ham = uieditfield(app.ohmTab, 'text');
            app.NhpdliuxEditField_3Label = uilabel(app.ohmTab);
            app.NhpdliuxEditField_3Label.Text = 'Nhập dữ liệu x:';
            app.Data_x_Dao_ham = uieditfield(app.ohmTab, 'text');
            app.HocLabel = uilabel(app.ohmTab);
            app.HocLabel.Text = 'Hoặc';
            app.NhphmsEditFieldLabel = uilabel(app.ohmTab);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số:';
            app.Ham_so_Dao_ham = uieditfield(app.ohmTab, 'text');
            app.GitrsaisDropDownLabel = uilabel(app.ohmTab);
            app.GitrsaisDropDownLabel.Text = 'Giá trị sai số:';
            app.Saiso_daoham = uidropdown(app.ohmTab);
            app.Saiso_daoham.Items = {'O(h)', 'O(h^2)'};
            app.Saiso_daoham.Value = 'O(h)';
            app.PhngphpohmDropDownLabel = uilabel(app.ohmTab);
            app.PhngphpohmDropDownLabel.Text = 'Phương pháp đạo hàm:';
            app.PP_Dao_ham = uidropdown(app.ohmTab);
            app.PP_Dao_ham.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Trung tâm'};
            app.PP_Dao_ham.Value = 'Xấp xỉ tiến';
            app.Cachnhap1 = uicheckbox(app.ohmTab);
            app.Cachnhap1.ValueChangedFcn = createCallbackFcn(app, @Cachnhap1ValueChanged, true);
            app.Cachnhap1.Text = 'Phương án 1';
            app.Cachnhap2 = uicheckbox(app.ohmTab);
            app.Cachnhap2.ValueChangedFcn = createCallbackFcn(app, @Cachnhap2ValueChanged, true);
            app.Cachnhap2.Text = 'Phương án 2';
            app.Tinh_Dao_ham = uibutton(app.ohmTab, 'push');
            app.Tinh_Dao_ham.ButtonPushedFcn = createCallbackFcn(app, @Tinh_Dao_hamButtonPushed, true);
            app.Tinh_Dao_ham.Text = 'Thực hiện';
            app.NhapgiatrixcantinhLabel = uilabel(app.ohmTab);
            app.NhapgiatrixcantinhLabel.Text = 'Nhap gia tri x can tinh:';
            app.X_Du_doan_Dao_ham = uieditfield(app.ohmTab, 'text');
            app.X_Du_doan_Dao_ham.Value = '0';
            app.KtquEditField_3Label = uilabel(app.ohmTab);
            app.KtquEditField_3Label.Text = 'Kết quả';
            app.Y_Du_doan_Dao_ham = uieditfield(app.ohmTab, 'text');
            app.NhpbcnhyhLabel = uilabel(app.ohmTab);
            app.NhpbcnhyhLabel.Text = 'Nhập bước nhảy h:';
            app.h_daoham = uieditfield(app.ohmTab, 'numeric');
            app.TchphnTab = uitab(app.TabGroup);
            app.TchphnTab.Title = 'Tích phân';
            app.Image6 = uiimage(app.TchphnTab);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'ThanhDat3_1-01.png');
            app.HocLabel_2 = uilabel(app.TchphnTab);
            app.HocLabel_2.Text = 'Hoặc';
            app.NhpdliuxEditField_4Label = uilabel(app.TchphnTab);
            app.NhpdliuxEditField_4Label.Text = 'Nhập dữ liệu x:';
            app.TP_X_value = uieditfield(app.TchphnTab, 'text');
            app.NhpdliuyEditField_4Label = uilabel(app.TchphnTab);
            app.NhpdliuyEditField_4Label.Text = 'Nhập dữ liệu y:';
            app.TP_Y_value = uieditfield(app.TchphnTab, 'text');
            app.NhphmsEditField_2Label = uilabel(app.TchphnTab);
            app.NhphmsEditField_2Label.Text = 'Nhập hàm số:';
            app.TP_HamSo = uieditfield(app.TchphnTab, 'text');
            app.PhngphpTchphnDropDownLabel = uilabel(app.TchphnTab);
            app.PhngphpTchphnDropDownLabel.Text = 'Phương pháp Tích phân:';
            app.TP_PhuongPhap = uidropdown(app.TchphnTab);
            app.TP_PhuongPhap.Items = {'Simpson 1/3', 'Simpson 3/8'};
            app.TP_PhuongPhap.Value = 'Simpson 1/3';
            app.KtquEditField_2Label = uilabel(app.TchphnTab);
            app.KtquEditField_2Label.Text = 'Kết quả:';
            app.TP_KQ = uieditfield(app.TchphnTab, 'numeric');
            app.NhpcntnhtchphnEditFieldLabel = uilabel(app.TchphnTab);
            app.NhpcntnhtchphnEditFieldLabel.Text = 'Nhập cận để tính tích phân:';
            app.TP_Can = uieditfield(app.TchphnTab, 'text');
            app.TP_Can.Value = '0';
            app.TP_Calc = uibutton(app.TchphnTab, 'push');
            app.TP_Calc.ButtonPushedFcn = createCallbackFcn(app, @TP_CalcButtonPushed, true);
            app.TP_Calc.Text = 'Tính toán';
            app.ResetButton_4 = uibutton(app.TchphnTab, 'push');
            app.ResetButton_4.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_4Pushed, true);
            app.ResetButton_4.Text = 'Reset';
            app.NhpNEditFieldLabel_2 = uilabel(app.TchphnTab);
            app.NhpNEditFieldLabel_2.Text = 'Nhập N:';
            app.TP_N = uieditfield(app.TchphnTab, 'numeric');
            app.Label = uilabel(app.TchphnTab);
            app.Label.Text = '';
            app.InputMeth = uiswitch(app.TchphnTab, 'slider');
            app.InputMeth.Items = {'Cách nhập 1', 'Cách nhập 2'};
            app.InputMeth.ItemsData = [0 1];
            app.InputMeth.ValueChangedFcn = createCallbackFcn(app, @InputMethValueChanged, true);
            app.InputMeth.Value = 0;
            app.Image7 = uiimage(app.TchphnTab);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'ThanhDat3_2-01.png');
            app.Image8 = uiimage(app.TchphnTab);
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'ThanhDat3_3-01.png');
            app.Image8_2 = uiimage(app.TchphnTab);
            app.Image8_2.ImageSource = fullfile(pathToMLAPP, 'ThanhDat3_4-01.png');
            app.GiithiunhmTab = uitab(app.TabGroup);
            app.GiithiunhmTab.Title = 'Giới thiệu nhóm';
            app.Image2 = uiimage(app.GiithiunhmTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Hung2-01.png');
            app.GiithiuthnhvinLabel = uilabel(app.GiithiunhmTab);
            app.GiithiuthnhvinLabel.Text = {'Giới thiệu '; 'thành viên:'};
            app.GiithiuthnhvinTextArea = uitextarea(app.GiithiunhmTab);
            app.GiithiuthnhvinTextArea.Value = {'Bùi Thành Đạt - 21207001'; 'Nguyễn Thị Trà Chi - 21207124'; 'Lê Quang Đăng - 21207130'; 'Trịnh Huy Hoàng - 21207156'; 'Phạm Đức Hùng - 21207158'; 'Thái Ngọc Duy Kha - 21207167'};
            app.GiithiunhmTextAreaLabel = uilabel(app.GiithiunhmTab);
            app.GiithiunhmTextAreaLabel.Text = 'Giới thiệu nhóm:';
            app.GiithiunhmTextArea = uitextarea(app.GiithiunhmTab);
            app.GiithiunhmTextArea.Value = {'Để thực hiện đồ án thiết kế app trên Matlab App Designer để giải quyết các bài toán phương pháp tính bao gồm giao diện và luận lý, nhóm đã được thành lập bao gồm 6 thành viên nhằm thực hiện tốt nhất các nội dung trong đồ án với các vai trò và nhiệm vụ. Với tinh thần trách nhiệm, tích cực trao đổi và khả năng thực hiện các công việc cao, cả nhóm đã hoàn thiện sản phẩm đáp ứng được các tiêu chí ban đầu đề ra, đồng thời đã cải thiện thêm một số hạn chế để phù hợp và thuận tiện hơn trong quá trình sử dụng. '};
            app.NhimvTextAreaLabel = uilabel(app.GiithiunhmTab);
            app.NhimvTextAreaLabel.Text = 'Nhiệm vụ:';
            app.NhimvTextArea = uitextarea(app.GiithiunhmTab);
            app.NhimvTextArea.Value = {'Code phần Nghiệm: Thái Ngọc Duy Kha'; 'Code phần Nội Suy: Nguyễn Thị Trà Chi'; 'Code Phần Hồi quy: Trịnh Huy Hoàng'; 'Code phần Đạo hàm: Lê Quang Đăng'; 'Code phần Tích phân: Bùi Thành Đạt'; 'Thiết kế giao diện và App: Phạm Đức Hùng'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BaiTapNhom3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end